classdef simpleWand < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        simpleWandUIFigure              matlab.ui.Figure
        ProjectMenu                     matlab.ui.container.Menu
        NewprojectMenu                  matlab.ui.container.Menu
        LoadasavedprojectMenu           matlab.ui.container.Menu
        SavethisprojectMenu             matlab.ui.container.Menu
        ExportcameraprofileMenu         matlab.ui.container.Menu
        ExportDLTcoefficientsMenu       matlab.ui.container.Menu
        QuitMenu                        matlab.ui.container.Menu
        DataMenu                        matlab.ui.container.Menu
        LoadpairedpointsMenu            matlab.ui.container.Menu
        LoadunpairedpointsMenu          matlab.ui.container.Menu
        LoadalignmentfileMenu           matlab.ui.container.Menu
        LoadacameraprofileMenu          matlab.ui.container.Menu
        SetimageoriginMenu              matlab.ui.container.Menu
        OperationsMenu                  matlab.ui.container.Menu
        computecalibrationMenu          matlab.ui.container.Menu
        clearoutlierdesignationsMenu    matlab.ui.container.Menu
        setworst5asoutliersMenu         matlab.ui.container.Menu
        HelpMenu                        matlab.ui.container.Menu
        AboutMenu                       matlab.ui.container.Menu
        LicenseMenu                     matlab.ui.container.Menu
        ManualMenu                      matlab.ui.container.Menu
        CheckforupdateMenu              matlab.ui.container.Menu
        msgTxtArea                      matlab.ui.control.TextArea
        TabGroup                        matlab.ui.container.TabGroup
        PairedpointsTab                 matlab.ui.container.Tab
        ShowpointsfromDropDown_2        matlab.ui.control.DropDown
        ShowpointsfromDropDown_2Label   matlab.ui.control.Label
        PairedpointsfileEditField       matlab.ui.control.EditField
        PairedpointsfileEditFieldLabel  matlab.ui.control.Label
        pairedPointsTable               matlab.ui.control.Table
        InterpointdistanceEditField     matlab.ui.control.NumericEditField
        InterpointdistanceEditFieldLabel  matlab.ui.control.Label
        UnpairedpointsTab               matlab.ui.container.Tab
        ShowpointsfromDropDown          matlab.ui.control.DropDown
        ShowpointsfromDropDownLabel     matlab.ui.control.Label
        UnpairedpointsfileEditField     matlab.ui.control.EditField
        UnpairedpointsfileEditFieldLabel  matlab.ui.control.Label
        unpairedPointsTable             matlab.ui.control.Table
        AlignmentPointsTab              matlab.ui.container.Tab
        alignmentPointsTable            matlab.ui.control.Table
        AlignmentsettingsPanel          matlab.ui.container.Panel
        NosettingsforthisalignmenttypeLabel  matlab.ui.control.TextArea
        ElevationangleofthelastcameradegreesEditField  matlab.ui.control.NumericEditField
        ElevationangleofthelastcameradegreesEditFieldLabel  matlab.ui.control.Label
        AzimuthangleofthelastcameradegreesEditField  matlab.ui.control.NumericEditField
        AzimuthangleofthelastcameradegreesEditFieldLabel  matlab.ui.control.Label
        CamerarecordingfrequencyHzEditField  matlab.ui.control.NumericEditField
        CamerarecordingfrequencyHzEditFieldLabel  matlab.ui.control.Label
        AlignmentfileEditField          matlab.ui.control.EditField
        AlignmentfileEditFieldLabel     matlab.ui.control.Label
        AlignmenttypeDropDown           matlab.ui.control.DropDown
        AlignmenttypeDropDownLabel      matlab.ui.control.Label
        ScaleTab                        matlab.ui.container.Tab
        CameraDistanceTable             matlab.ui.control.Table
        SetscenescalefrommeasuredcameracameradistancesCheckBox  matlab.ui.control.CheckBox
        cameraTable                     matlab.ui.control.Table
        plotAxes                        matlab.ui.control.UIAxes
    end


    properties (Access = private)
        wandPts % wand points struct with filename, path, data
        bkgPts % background points struct
        axisPts % alignment points struct
        extrinsics % struct with extrinsics.poses and extrinsics.positions, both cell arrays
        numCams = 1 % number of cameras in this project
        version = [0 8 6]; % version information
        wandScore % calibration wand score
        distanceScore % calibration camera distance score
        rmse % calibration summary rmse for each camera
        dltcoefs % calibration DLT coefficients
        intrinsics % processed camera intrinsics
        licenseText = {... % License text
            'license.txt for the simpleWand app', ...
            ' ', ...
            'Copyright 2021 Tyson Hedrick', ...
            ' ', ...
            'License: BSD-3-Clause', ...
            'Redistribution and use in source and binary forms, with or without modification, are permitted provided that the following conditions are met:', ...
            '1. Redistributions of source code must retain the above copyright notice, this list of conditions and the following disclaimer.', ...
            '2. Redistributions in binary form must reproduce the above copyright notice, this list of conditions and the following disclaimer in the documentation and/or other materials provided with the distribution.', ...
            '3. Neither the name of the copyright holder nor the names of its contributors may be used to endorse or promote products derived from this software without specific prior written permission.', ...
            'THIS SOFTWARE IS PROVIDED BY THE COPYRIGHT HOLDERS AND CONTRIBUTORS "AS IS" AND ANY EXPRESS OR IMPLIED WARRANTIES, INCLUDING, BUT NOT LIMITED TO, THE IMPLIED WARRANTIES OF MERCHANTABILITY ', ...
            'AND FITNESS FOR A PARTICULAR PURPOSE ARE DISCLAIMED. IN NO EVENT SHALL THE COPYRIGHT HOLDER OR CONTRIBUTORS BE LIABLE FOR ANY DIRECT, INDIRECT, INCIDENTAL, SPECIAL, EXEMPLARY, OR CONSEQUENTIAL ', ...
            'DAMAGES (INCLUDING, BUT NOT LIMITED TO, PROCUREMENT OF SUBSTITUTE GOODS OR SERVICES; LOSS OF USE, DATA, OR PROFITS; OR BUSINESS INTERRUPTION) HOWEVER CAUSED AND ON ANY THEORY OF LIABILITY, WHETHER ', ...
            'IN CONTRACT, STRICT LIABILITY, OR TORT (INCLUDING NEGLIGENCE OR OTHERWISE) ARISING IN ANY WAY OUT OF THE USE OF THIS SOFTWARE, EVEN IF ADVISED OF THE POSSIBILITY OF SUCH DAMAGE.' ...
            };
        imageOriginLowerLeft = 1; % Sets the assumed location of the image coordinate origin, either upper left or lower left (i.e. imageOriginLowerLeft = 1).
    end

    methods (Access = private)

        function [] = plot3DReconstruct(app)
            % plot the scene in the GUI figure

            cla(app.plotAxes);  % Clear figure axes
            hold(app.plotAxes,'on'); % set hold mode
            meanpt=inanmean([app.wandPts.xyz(:,1:3);app.wandPts.xyz(:,4:6);app.bkgPts.xyz])';  % Get the centroid for scale

            %             % Check for points marked for removal
            %             rmpts=uda.rmpts;
            %             bkrmpts=uda.bkrmpts;
            %             w1=uda.w1;
            %             w1(rmpts(:,1),:)=NaN;
            %             w2=uda.w2;
            %             w2(rmpts(:,2),:)=NaN;

            % Plot the wand points in blue
            w1=app.wandPts.xyz(:,1:3);
            w2=app.wandPts.xyz(:,4:6);

            plot3(app.plotAxes,w1(:,1),w1(:,2),w1(:,3),'b.');
            plot3(app.plotAxes,w2(:,1),w2(:,2),w2(:,3),'b.')
            idx=find(isnan(w1(:,1))==false & isnan(w2(:,1))==false);
            for i=1:numel(idx)
                plot3(app.plotAxes,[w1(idx(i),1);w2(idx(i),1)],[w1(idx(i),2);w2(idx(i),2)],...
                    [w1(idx(i),3);w2(idx(i),3)],'b-');
            end

            % grab the background points and plot them in green
            bkgPts=app.bkgPts.xyz;
            plot3(app.plotAxes,bkgPts(:,1),bkgPts(:,2),bkgPts(:,3),'g.')


            % grab the axis points and plot them in red
            axPts=app.axisPts.xyz;
            plot3(app.plotAxes,axPts(:,1),axPts(:,2),axPts(:,3),'mo')
            if sum(isfinite(axPts(:,1)))<5
                for i=2:size(axPts,1)
                    plot3(app.plotAxes,[axPts(1,1);axPts(i,1)],[axPts(1,2);axPts(i,2)],...
                        [axPts(1,3);axPts(i,3)],'r-')
                end
            end


            % get camera position and orientation
            for i=1:numel(app.extrinsics.poses)
                %[xyz,T] = DLTcameraPosition(uda.coefs(:,i));
                xyz=app.extrinsics.positions{i};
                pose=app.extrinsics.poses{i}';
                T = [pose,xyz'*0;xyz,1];
                % Primary cameras in black, secondary cameras in red
                %if uda.calibrationMode(i)
                plot3(app.plotAxes,xyz(1),xyz(2),xyz(3),'ks','markerFaceColor','k','markerSize',6)
                text(app.plotAxes,xyz(1),xyz(2),xyz(3),sprintf('    Camera %.0f',i),'Color','k')

                foo=T*[0;0;(norm(xyz-meanpt)/2);0];
                plot3(app.plotAxes,[xyz(1);xyz(1)+foo(1)],[xyz(2);xyz(2)+foo(2)],...
                    [xyz(3);xyz(3)+foo(3)],'k-')
                %                 else
                %                     plot3(app.plotAxes,xyz(1),xyz(2),xyz(3),'rs','markerFaceColor','r','markerSize',6)
                %                     text(app.plotAxes,xyz(1),xyz(2),xyz(3),sprintf('    Camera %.0f',i),'Color','r')
                %
                %                     foo=T*[0;0;-(norm(xyz-meanpt)/2);0];
                %                     plot3(app.plotAxes,[xyz(1);xyz(1)+foo(1)],[xyz(2);xyz(2)+foo(2)],...
                %                         [xyz(3);xyz(3)+foo(3)],'r-')
                %                 end
            end
            xlabel(app.plotAxes,'X'); ylabel(app.plotAxes,'Y'); zlabel(app.plotAxes,'Z');
            axis(app.plotAxes,'equal');
            rotate3d(app.plotAxes,'on');
            hold(app.plotAxes,'off');
            view(app.plotAxes,3);

            %             if isfield(uda,'gravityDir')
            %                 if uda.gravityDir==1
            %                     set(gca,'zdir','reverse','ydir','reverse')
            %                 else
            %                     set(gca,'zdir','normal','ydir','normal')
            %                 end
            %             end

            return
        end

        function [] = updateUnpairedPointsGrid(app)
            % updates the unpaired points UI table
            cam=app.ShowpointsfromDropDown.Value;
            app.unpairedPointsTable.Data=table(app.bkgPts.ID,app.bkgPts.data(:,cam*2-1),...
                app.bkgPts.data(:,cam*2),app.bkgPts.xyz(:,1),app.bkgPts.xyz(:,2),...
                app.bkgPts.xyz(:,3),app.bkgPts.rmse,app.bkgPts.outlier);
        end

        function [] = dlt_computeCoefficients(app)

            % function  [c,rmse] = dlt_computeCoefficients(frame,camPts)
            %
            % A basic implementation of 11 parameter DLT
            %
            % Inputs:
            %  frame - an array of x,y,z calibration point coordinates
            %  camPts - an array of u,v pixel coordinates from the camera
            %
            % Outputs:
            %  c - the 11 DLT coefficients
            %  rmse - root mean square error for the reconstruction; units =
            %
            % Notes - frame and camPts must have the same number of rows.  A minimum of
            % 6 rows are required to compute the coefficients.  The frame points must
            % not all lie within a single plane
            %
            % Ty Hedrick

            % gather xyz data
            baseFrame=[app.wandPts.xyz(:,1:3);app.wandPts.xyz(:,4:6);app.bkgPts.xyz]; % note - outlier points have a NaN xyz
            %baseFrame=baseFrame-inanmean(baseFrame); % need to move the DLT origin away from the base camera

            for C=1:app.numCams
                frame=baseFrame;
                camPts=[app.wandPts.data(:,C*2-1:C*2);...
                    app.wandPts.data(:,(C*2-1:C*2)+2*app.numCams);...
                    app.bkgPts.data(:,C*2-1:C*2)];

                % undistort the camera points if necessary
                %camPts(isfinite(camPts(:,1)),:)=undistortPoints(camPts(isfinite(camPts(:,1)),:),app.intrinsics(C));
                camPts(isfinite(camPts(:,1)),:)=undistortFast(app,camPts(isfinite(camPts(:,1)),:),app.intrinsics(C));

                % check for any NaN rows (missing data) in the frame or camPts
                ndx=find(sum(isnan([frame,camPts]),2)>0);

                % remove any missing data rows
                frame(ndx,:)=[];
                camPts(ndx,:)=[];

                % re-arrange the frame matrix to facilitate the linear least squares
                % solution
                M=zeros(size(frame,1)*2,11);
                for i=1:size(frame,1)
                    M(2*i-1,1:3)=frame(i,1:3);
                    M(2*i,5:7)=frame(i,1:3);
                    M(2*i-1,4)=1;
                    M(2*i,8)=1;
                    M(2*i-1,9:11)=frame(i,1:3).*-camPts(i,1);
                    M(2*i,9:11)=frame(i,1:3).*-camPts(i,2);
                end

                % re-arrange the camPts array for the linear solution
                camPtsF=reshape(flipud(rot90(camPts)),numel(camPts),1);

                % get the linear solution to the 11 parameters
                c=linsolve(M,camPtsF);

                % compute the position of the frame in u,v coordinates given the linear
                % solution from the previous line
                Muv=[];
                Muv(:,1)=(frame(:,1).*c(1)+frame(:,2).*c(2)+frame(:,3).*c(3)+c(4))./ ...
                    (frame(:,1).*c(9)+frame(:,2).*c(10)+frame(:,3).*c(11)+1);
                Muv(:,2)=(frame(:,1).*c(5)+frame(:,2).*c(6)+frame(:,3).*c(7)+c(8))./ ...
                    (frame(:,1).*c(9)+frame(:,2).*c(10)+frame(:,3).*c(11)+1);

                % compute the root mean square error between the ideal frame u,v and the
                % recorded frame u,v
                app.rmse(C)=(sum(sum((Muv-camPts).^2))./numel(camPts))^0.5;
                app.dltcoefs(:,C)=c;
            end
        end

        function [coefs2] = dlt_convertCoefficients(app)
            eTrans=[[eye(3),1e1*[1,10,10]'];[0,0,0,1]]; % change camera position & orientation for numeric conditioning
            %eTrans=[[eye(3),[0.0022   -0.0386    0.4174]';[0,0,0,1]]];
            % identity matrix with extra column for compatibility with 4x4 transforms
            m=[eye(3),[0;0;0]];

            for i=1:app.numCams
                %K=[F(i),0,pp(i*2-1);0,F(i),pp(i*2);0,0,1];
                K=[app.intrinsics(i).FocalLength(1),0,app.intrinsics(i).PrincipalPoint(1); ...
                    0,app.intrinsics(i).FocalLength(1),app.intrinsics(i).PrincipalPoint(2); ...
                    0,0,1];

                % camera rotations + translation as a 4x4 transform matrix
                %P1=[R(:,:,i),tv(:,:,i)'];
                %P1=[app.extrinsics.poses{i},app.extrinsics.poses{i}*app.extrinsics.positions{i}'];% best so far
                P1=[app.extrinsics.poses{i},app.extrinsics.poses{i}*-app.extrinsics.positions{i}'];% best so far
                %
                % P1=[app.extrinsics.poses{i}',(app.extrinsics.positions{i}*app.extrinsics.poses{i}')'];
                %P1=[app.extrinsics.poses{i},app.extrinsics.positions{i}'];
                P1e=[P1;[0,0,0,1]];

                %coefsraw=K*m*P1e*eTrans;
                coefsraw=K*m*P1e;
                coefs(:,i)=reshape(coefsraw',12,1);

                coefs2(:,i)=coefs(:,i)./coefs(end,i);
            end
            coefs2=coefs2(1:11,:);
        end

        function [] = updatePairedPointsGrid(app)
            % updates the unpaired points UI table
            camOpt=app.ShowpointsfromDropDown_2.Value;
            cam=ceil(camOpt/2);
            ptOff=abs(mod(camOpt,2)-1)*app.numCams*2; % uv array offset for point 2
            xyzOff=abs(mod(camOpt,2)-1)*3; % xyz array offset for point 2
            sOff=abs(mod(camOpt,2)-1)+1; % offset for 2-column arrays
            dt=table(app.wandPts.ID,app.wandPts.data(:,cam*2-1+ptOff),...
                app.wandPts.data(:,cam*2+ptOff),app.wandPts.xyz(:,1+xyzOff), ...
                app.wandPts.xyz(:,2+xyzOff), app.wandPts.xyz(:,3+xyzOff), ...
                app.wandPts.rmse(:,sOff),app.wandPts.wandLength,...
                app.wandPts.outlier(:,sOff));
            app.pairedPointsTable.Data=dt;
        end

        function [] = updateAlignmentOptionVisibility(app)
            % app.AlignmenttypeDropDown.Value options
            % 1 = 4 point axis
            % 2 = 3 point axis
            % 3 = plane
            % 4 = gravity
            % 5 = known azimuth and elevation
            % 6 = known 3D points
            if app.AlignmenttypeDropDown.Value==5 % azimuth & elevation
                app.NosettingsforthisalignmenttypeLabel.Visible=false;
                app.CamerarecordingfrequencyHzEditField.Visible=false;
                app.CamerarecordingfrequencyHzEditFieldLabel.Visible=false;
                app.AzimuthangleofthelastcameradegreesEditField.Visible=true;
                app.AzimuthangleofthelastcameradegreesEditFieldLabel.Visible=true;
                app.ElevationangleofthelastcameradegreesEditField.Visible=true;
                app.ElevationangleofthelastcameradegreesEditFieldLabel.Visible=true;
            elseif app.AlignmenttypeDropDown.Value==4 % gravity
                app.NosettingsforthisalignmenttypeLabel.Visible=false;
                app.CamerarecordingfrequencyHzEditField.Visible=true;
                app.CamerarecordingfrequencyHzEditFieldLabel.Visible=true;
                app.AzimuthangleofthelastcameradegreesEditField.Visible=false;
                app.AzimuthangleofthelastcameradegreesEditFieldLabel.Visible=false;
                app.ElevationangleofthelastcameradegreesEditField.Visible=false;
                app.ElevationangleofthelastcameradegreesEditFieldLabel.Visible=false;
            else
                app.NosettingsforthisalignmenttypeLabel.Visible=true;
                app.CamerarecordingfrequencyHzEditField.Visible=false;
                app.CamerarecordingfrequencyHzEditFieldLabel.Visible=false;
                app.AzimuthangleofthelastcameradegreesEditField.Visible=false;
                app.AzimuthangleofthelastcameradegreesEditFieldLabel.Visible=false;
                app.ElevationangleofthelastcameradegreesEditField.Visible=false;
                app.ElevationangleofthelastcameradegreesEditFieldLabel.Visible=false;
            end

            if app.AlignmenttypeDropDown.Value==1 % 4 point axis
                app.NosettingsforthisalignmenttypeLabel.Value=...
                    {['No settings for this alignment type, load an alignment file', ...
                    ' with the pixel coordinates of the origin and a point on the', ...
                    ' +X, +Y, and +Z axes in that order.']};
            elseif app.AlignmenttypeDropDown.Value==2 % 3 point axis
                app.NosettingsforthisalignmenttypeLabel.Value=...
                    {['No settings for this alignment type, load an alignment file', ...
                    ' with the pixel coordinates of the origin and a point on the', ...
                    ' +X and +Y axes in that order. The +Z direction will be set', ...
                    ' by the right hand rule.']};
            elseif app.AlignmenttypeDropDown.Value==3 % plane
                app.NosettingsforthisalignmenttypeLabel.Value=...
                    {['No settings for this alignment type, load an alignment file', ...
                    ' containing the pixel coordinates of at least 3 points that', ...
                    'define a plane. The +X and +Y axes will define the horizontal ', ...
                    'plane; +Z will point toward the majority of calibration points.']};
            elseif app.AlignmenttypeDropDown.Value==6 % known 3D points
                app.NosettingsforthisalignmenttypeLabel.Value=...
                    {['No settings for this alignment type, load an alignment file', ...
                    ' with the pixel coordinates of at least 3 points with known', ...
                    ' 3D locations, provided in the last 3 columns of the file. A', ...
                    ' simpleWand project with 3 cameras will have a 3x2+3 = 9 column', ...
                    ' alignment file of this type, with at least 3 rows.']}; 
            else
                app.NosettingsforthisalignmenttypeLabel.Value=...
                    {['No settings for this alignment type.']};
            end
        end

        function g = polyFitGravity(app,axisXYZ)
            % Fits a 2nd order polynomial to a the time-series of position of a falling
            % object to get the gravitational acceleration vector.

            gravityFreq=app.CamerarecordingfrequencyHzEditField.Value;

            t=(0:size(axisXYZ,1)-1)';
            idx=find(sum(isnan(axisXYZ),2)==0);
            p1=polyfit(t(idx),axisXYZ(idx,1),2);
            p2=polyfit(t(idx),axisXYZ(idx,2),2);
            p3=polyfit(t(idx),axisXYZ(idx,3),2);
            g1(:,1)=polyval(p1,t);
            g1(:,2)=polyval(p2,t);
            g1(:,3)=polyval(p3,t);
            g=diff(diff(g1)).*gravityFreq.^2;
            g=inanmean(g);

        end

        function uvdd = undistortFast(app,uv,intrinsics)
            % function [uvdd] = undisortFast(app,uv,intrinsics)
            %
            % Adapted from easyWand5 undistort because of huge performance
            % problems with MATLAB undistortPoints
            %
            % function [uvdd] = undistort(app,uv,f,UoVo,nlin,niter)
            %
            % inputs:
            %  uv - array of pixel coordinates, distorted
            %  f - focal length
            %  UoVo - principal point
            %  nlin - nonlinear distortion coefficients
            %  niter - number of undistort iterations, 5 is usually more than good
            %
            % outputs:
            %  uvdd - array of pixel coordinates, undistorted
            %
            % Iteratively applies undistortion coefficients to estimate undistorted
            % pixel coordinates from observation of distorted pixel coordinates

            % parse intrinsics
            f = intrinsics.FocalLength(1);
            UoVo = intrinsics.PrincipalPoint;
            nlin = [intrinsics.RadialDistortion(1:2),...
                intrinsics.TangentialDistortion,intrinsics.RadialDistortion(3)];
            niter = 10; % identical to undistortPoints to ~1e-12

            % create normalized points from pixel coordinates
            uvn = (uv - repmat(UoVo,size(uv,1),1))./f;

            uvnd = uvn;

            % undistort (niter iterations)
            for i=1:niter
                r2=rnorm(uvnd).^2; % square of the radius
                rad = 1 + nlin(1)*r2 + nlin(2)*r2.^2 + nlin(5)*r2.^3; % radial distortion
                tan = [2*nlin(3).*uvnd(:,1).*uvnd(:,2) + nlin(4)*(r2 + 2*uvnd(:,1).^2)];
                tan(:,2) = nlin(3)*(r2 + 2*uvnd(:,2).^2) + 2*nlin(4).*uvnd(:,1).*uvnd(:,2);

                uvnd = (uvn - tan)./repmat(rad,1,2);
            end

            % restore pixel coordinates
            uvdd = uvnd*f + repmat(UoVo,size(uv,1),1);

        end
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            % check for computer vision toolbox
            if exist('cameraIntrinsics','file')==0
                fprintf('simpleWand requires the MATLAB Computer Vision and Image Analysis toolbox,\nbut it does not appear to be installed.\n')
                fprintf('Exiting.\n')
                delete(app);
                return
            end

            % set alignment menu options - start with azimuth/elevation because
            % that one produces sensible output with no further inputs
            app.AlignmenttypeDropDown.ItemsData=1:6;
            app.AlignmenttypeDropDown.Value=5;

            app.updateAlignmentOptionVisibility();

            % display welcome text
            msg={};
            msg{1}='Welcome to simpleWand';
            msg{2}=sprintf('Version %d.%d.%d',app.version(1),app.version(2),app.version(3));
            app.msgTxtArea.Value=msg;
        end

        % Menu selected function: NewprojectMenu
        function NewprojectMenuSelected(app, event)
            msg=app.msgTxtArea.Value;
            answer = inputdlg('How many cameras does your project have?','How many cameras?');
            if isempty(answer) % user cancel
                msg{numel(msg)+1}=['New project canceled.'];
                app.msgTxtArea.Value=msg;
                return
            end
            try
            ncams=round(str2num(answer{1}));
            msg{numel(msg)+1}=['Creating a ',num2str(ncams),' camera project.'];
            app.msgTxtArea.Value=msg;
            app.numCams=ncams;
            catch % bad inputs
                msg{numel(msg)+1}=['New project: bad input, please try again.'];
                app.msgTxtArea.Value=msg;
                return
            end

            % initialize the data arrays
            app.bkgPts.data=zeros(0,ncams*2);
            app.bkgPts.ID=zeros(0,1);
            app.bkgPts.outlier=zeros(0,1);
            app.bkgPts.xyz=zeros(0,3);
            app.bkgPts.rmse=zeros(0,1);

            app.wandPts.data=zeros(0,ncams*4);
            app.wandPts.ID=zeros(0,1);
            app.wandPts.outlier=zeros(0,2);
            app.wandPts.xyz=zeros(0,6);
            app.wandPts.rmse=zeros(0,2);
            app.wandPts.wandLength=zeros(0,1);

            app.axisPts.data=zeros(0,ncams*2);
            app.axisPts.xyz=zeros(0,3);
            app.axisPts.ID=zeros(0,1);


            % populate camera intrinsics table
            num=(1:ncams)';
            primary=true(size(num));
            Uo=num*NaN;
            Vo=num*NaN;
            f=num*NaN;
            d2=num*0;
            d4=num*0;
            d6=num*0;
            app.cameraTable.Data=table(num,primary,Uo,Vo,f,d2,d4,d6);

            % populate camera distance table
            camDist=[];
            for i=1:ncams-1
                for j=i+1:ncams
                    camDist=[camDist;[i,j]];
                end
            end
            camDist(:,3)=NaN;
            app.CameraDistanceTable.Data=table(camDist(:,1),camDist(:,2),camDist(:,3));

            % turn on the UI
            app.NewprojectMenu.Enable=false;
            app.LoadasavedprojectMenu.Enable=false;
            app.SavethisprojectMenu.Enable=true;
            app.ExportcameraprofileMenu.Enable=true;
            app.DataMenu.Enable=true;

            % setup the camera menu in the paired and unpaired points tabs
            camNames={};
            camNums=1:ncams;
            for i=1:ncams
                camNames{i}=['Camera ',num2str(i)];
            end
            app.ShowpointsfromDropDown.Items=camNames;
            app.ShowpointsfromDropDown.ItemsData=camNums;

            camNames={};
            camOpts=1:ncams*2;
            for i=1:ncams*2
                camNames{i}=['Cam ',num2str(ceil(i/2)),' pt ',num2str(abs(mod(i,2)-1)+1)];
            end
            app.ShowpointsfromDropDown_2.Items=camNames;
            app.ShowpointsfromDropDown_2.ItemsData=camOpts;


        end

        % Menu selected function: LoadpairedpointsMenu
        function LoadpairedpointsMenuSelected(app, event)
            [filename, pathname] = uigetfile({'*.csv', 'Comma separated array';'*.tsv','Sparse array file'});
            if filename==0
                % cancel
                return
            end
            try
                if strcmpi(filename(end-2:end),'csv')
                    %foo=importdata([pathname,filesep,filename]);
                    foo=table2array(readtable([pathname,filesep,filename]));
                    if isstruct(foo)
                        foo=foo.data;
                    end
                    ID=find(sum(~isnan(foo),2)>0);
                    foo=foo(ID,:);
                elseif strcmpi(filename(end-2:end),'tsv')
                    foo=sparseRead([pathname,filesep,filename]);
                    ID=find(sum(foo~=0,2)>0);
                    foo=foo(ID,:);
                    foo=sp2full(foo);
                end

                % input sanity check
                if size(foo,2)~=size(app.cameraTable.Data,1)*4
                    app.msgTxtArea.Value={['This wand points file is the wrong size - it should have 4 columns for each camera.']};
                    return;
                end

                app.wandPts.filename=filename;
                app.wandPts.pathname=pathname;
                app.wandPts.data=foo;
                app.wandPts.xyz=nan(size(foo,1),6);
                app.wandPts.rmse=nan(size(foo,1),2);
                app.wandPts.outlier=false(size(foo,1),2);
                app.wandPts.wandLength=nan(size(foo,1),1);
                app.wandPts.ID=ID;
                app.PairedpointsfileEditField.Value=[app.wandPts.pathname,app.wandPts.filename];
                app.OperationsMenu.Enable=true;


                % update UI table
                updatePairedPointsGrid(app)

                app.msgTxtArea.Value={['Loaded paired points file ',pathname,filename]};


            catch ME
                disp(ME)
                app.msgTxtArea.Value={'Could not parse the paired points file'};
            end

        end

        % Menu selected function: LoadunpairedpointsMenu
        function LoadunpairedpointsMenuSelected(app, event)
            [filename, pathname] = uigetfile(...
                {'*.csv;*.tsv','Text data files (*.csv,*.tsv)';...
                '*.csv','Comma separated array';...
                '*.tsv','Sparse array file'});
            if filename==0
                % cancel
                return
            end
            try
                if strcmpi(filename(end-2:end),'csv')
                    %foo=importdata([pathname,filesep,filename]);
                    foo=table2array(readtable([pathname,filesep,filename]));
                    if isstruct(foo)
                        foo=foo.data;
                    end
                    ID=find(sum(~isnan(foo),2)>0);
                    foo=foo(ID,:);
                elseif strcmpi(filename(end-2:end),'tsv')
                    foo=sparseRead([pathname,filesep,filename]);
                    ID=find(sum(foo~=0,2)>0);
                    foo=foo(ID,:);
                    foo=sp2full(foo);
                end

                % input sanity check
                if size(foo,2)~=size(app.cameraTable.Data,1)*2
                    app.msgTxtArea.Value={'This unpaired points file is the wrong size - it should have 2 columns for each camera.'};
                    return
                end

                % setup data structure
                app.bkgPts.filename=filename;
                app.bkgPts.pathname=pathname;
                app.bkgPts.data=foo;
                app.bkgPts.ID=ID;
                app.bkgPts.xyz=nan(size(foo,1),3);
                app.bkgPts.rmse=nan(size(foo,1),1);
                app.bkgPts.outlier=false(size(foo,1),1);
                app.UnpairedpointsfileEditField.Value=[app.bkgPts.pathname,app.bkgPts.filename];
                app.OperationsMenu.Enable=true;

                % update UI table
                updateUnpairedPointsGrid(app)
            catch ME
                disp(ME)
                app.msgTxtArea.Value={'Could not parse the unpaired points file'};
            end
        end

        % Menu selected function: LoadalignmentfileMenu
        function LoadalignmentfileMenuSelected(app, event)
            [filename, pathname] = uigetfile({'*.csv', 'Comma separated array';'*.tsv','Sparse array file'});
            if filename==0
                % cancel
                return
            end
            try
                if strcmpi(filename(end-2:end),'csv')
                    %foo=importdata([pathname,filesep,filename]);
                    foo=table2array(readtable([pathname,filesep,filename]));
                    if isstruct(foo)
                        foo=foo.data;
                    end
                    ID=find(sum(~isnan(foo),2)>0);
                    foo=foo(ID,:);
                elseif strcmpi(filename(end-2:end),'tsv')
                    foo=sparseRead([pathname,filesep,filename]);
                    ID=find(sum(foo~=0,2)>0);
                    foo=foo(ID,:);
                    foo=sp2full(foo);
                end

                app.axisPts.filename=filename;
                app.axisPts.pathname=pathname;
                app.axisPts.data=foo;
                app.axisPts.ID=ID;
                app.AlignmentfileEditField.Value=[app.axisPts.pathname,app.axisPts.filename];
            catch ME
                disp(ME)
                app.msgTxtArea.Value={'Could not parse the alignment points file'};
            end


        end

        % Menu selected function: LoadacameraprofileMenu
        function LoadacameraprofileMenuSelected(app, event)
            [filename, pathname] = uigetfile({'*.txt', 'easyWand, simpleWand or Argus camera profile'});
            if filename==0
                % cancel
                return
            end
            try
                foo=importdata([pathname,filename]);
                if size(foo,1)~=size(app.cameraTable.Data,1)
                    app.msgTxtArea.Value={'The number of cameras in the profile does not match the project'};
                    return
                end
                for i=1:size(foo,1)
                    j=foo(i,1); % get the camera ID
                    app.cameraTable.Data.f(j)=foo(i,2);
                    app.cameraTable.Data.Uo(j)=foo(i,5);
                    app.cameraTable.Data.Vo(j)=foo(i,6);
                    app.cameraTable.Data.d2(j)=foo(i,8);
                    app.cameraTable.Data.d4(j)=foo(i,9);
                    app.cameraTable.Data.d6(j)=foo(i,12);
                end
                app.msgTxtArea.Value={'Camera profile loaded'};
            catch ME
                disp(ME)
                app.msgTxtArea.Value={'Error parsing camera profile'};
            end
        end

        % Menu selected function: computecalibrationMenu
        function computecalibrationMenuSelected(app, event)
            % useful stuff
            wandPtsOffset=2*app.numCams;
            msg={};
            msg{1}='Starting calibration...';
            app.msgTxtArea.Value=msg;
            drawnow


            %% PRIMARY CAMERAS %%

            % check for camera data
            if sum(sum(isnan(table2array(app.cameraTable.Data(:,3:end)))))>0
                beep
                msg{numel(msg)+1}='You need to have a valid specification for all cameras before calibrating.';
                app.msgTxtArea.Value=msg;

                % flash the colors in the camera table
                bb=app.cameraTable.BackgroundColor;
                app.cameraTable.BackgroundColor=[1,0,0;0.8,0,0];
                pause(0.25)
                app.cameraTable.BackgroundColor=bb;
                return
            end

            % setup camera intrinsics
            pcams=find(app.cameraTable.Data.primary==true);
            if numel(pcams)<2
                msg{numel(msg)+1}='You need at least 2 primary cameras for a calibration';
                app.msgTxtArea.Value=msg;
                return
            end
            columnID=sort([pcams'*2-1,pcams'*2]); % column indices for the primary cameras in the uv data arrays


            for i=1:numel(pcams)
                intrinsics(i)=cameraIntrinsics(app.cameraTable.Data.f(pcams(i)), ...
                    [app.cameraTable.Data.Uo(pcams(i)),app.cameraTable.Data.Vo(pcams(i))], ...
                    [app.cameraTable.Data.Vo(pcams(i)),app.cameraTable.Data.Uo(pcams(i))]*2,... % correct?
                    'RadialDistortion',table2array(app.cameraTable.Data(pcams(i),6:8)));
            end
            % gather all points
            % paired
            try
                % all wand points, regardless of whether or not they're non-NaN
                wxy=[app.wandPts.data(:,[columnID,columnID+wandPtsOffset])];

                % mask outliers
                wxy(app.wandPts.outlier(:,1),1:numel(pcams)*2)=NaN;
                wxy(app.wandPts.outlier(:,2),numel(pcams)*2+1:end)=NaN;

            catch
                wxy=zeros(0,numel(pcams)*2);
            end
            % unpaired
            try
                % all background points, regardless of whether or not they're non-NaN
                bxy=[app.bkgPts.data(:,columnID)];

                % mask outliers
                bxy(app.bkgPts.outlier,:)=NaN;
            catch
                bxy=[];
            end
            % axis
            try
                if size(app.axisPts.data,2)~=3 % indicative of a camera-azimuth-elevation alignment
                    axy=[app.axisPts.data(:,columnID)];
                else
                    axy=[];
                end
            catch
                axy=[];
            end

            allxy=[wxy(:,1:numel(pcams)*2);wxy(:,numel(pcams)*2+1:end);bxy;axy];

            % undistort the camera points if necessary
            for i=1:numel(pcams)
                % Mathworks code - very very slow for large arrays
                % allxy(isfinite(allxy(:,i*2)),i*2-1:i*2)=undistortPoints(allxy(isfinite(allxy(:,i*2)),i*2-1:i*2),intrinsics(i));

                % Hedrick code - much faster :)
                allxy(isfinite(allxy(:,i*2)),i*2-1:i*2)=undistortFast(app,allxy(isfinite(allxy(:,i*2)),i*2-1:i*2),intrinsics(i));

            end

            % estimate fundamental matrix, pose & orientation for all but last camera
            for i=1:numel(pcams)-1
                efmxy=[allxy(:,end-1:end),allxy(:,i*2-1:i*2)];
                efmidx=find(sum(isfinite(efmxy),2)<4);
                efmxy(efmidx,:)=[];
                try
                    if size(efmxy,1)>1000
                        F = estimateFundamentalMatrix(efmxy(:,1:2),efmxy(:,3:4),'Method', 'Norm8Point');
                    else
                        F = estimateFundamentalMatrix(efmxy(:,1:2),efmxy(:,3:4),'Method', 'Norm8Point');
                    end

                catch ME
                    msg{numel(msg)+1}=['Error getting preliminary calibration for camera ',num2str(pcams(i))];
                    msg{numel(msg)+1}=['  Add more data for this camera or make it secondary.'];
                    msg{numel(msg)+1}=['  Aborting calibration.'];
                    app.msgTxtArea.Value=msg;
                    return
                end


                [a,b] = relativeCameraPose(F,intrinsics(end),intrinsics(i), efmxy(:,1:2),efmxy(:,3:4));
                %[R{i}, tv{i}] = cameraPoseToExtrinsics(a(:,:,1),b(1,:)); % not the right thing to do
                R{i}=a(:,:,1);
                tv{i}=b(1,:);

            end
            R{end+1}=eye(3);
            tv{end+1}=[0,0,0];
            ViewID=uint32(1:numel(pcams));

            % make poses table
            %cameraPoses=table(ViewID',R',tv','VariableNames',{'ViewId','Orientation','Location'});
            cameraPoses=table(ViewID',R',tv','VariableNames',{'ViewId','Orientation','Location'});

            % setup tracks
            for i=1:size(allxy,1)
                d=reshape(allxy(i,:),2,size(allxy,2)/2)';
                vID=ViewID(sum(isfinite(d),2)==2);
                if numel(vID)>1 %if isempty(vID)==false
                    %pointTracks(i)=pointTrack(ViewID,reshape(allxy(i,:),2,size(allxy,2)/2)');
                    pointTracks(i)=pointTrack(vID,d(vID,:));
                end
            end

            % get simple xyz result
            [xyzPoints,errors] = triangulateMultiview(pointTracks,cameraPoses,intrinsics);
            xyzPointsB=xyzPoints;
            xyzPoints(isfinite(xyzPoints)~=true)=NaN; % remove inf values, replace with NaN
            xyzPointsB(isfinite(xyzPointsB)~=true)=0; % enforce r2019b behavior in later versions for compatibility with bundleAdjustment()

            % bundle adjustment
            [xyzRefinedPoints,refinedPoses,refinedErrors] = bundleAdjustment(xyzPointsB,pointTracks,cameraPoses,intrinsics,'verbose',true,'pointsundistorted',true,'AbsoluteTolerance',.01);
            %       idx=find(xyzRefinedPoints(:,1)~=0);
            %       [c,rmse]=mdlt_computeCoefficients(xyzRefinedPoints(idx,:),-allxy(idx,1:2));
            %       [cxyz,cT,cypr,cUo,cVo,cZ] = DLTcameraPosition(c);
            %       R1=[1,0,0;0,cosd(cypr(1)),-sind(cypr(1));0,sind(cypr(1)),cosd(cypr(1))];
            %       R2=[cosd(cypr(2)),0,sind(cypr(2));0,1,0;-sind(cypr(2)),0,cosd(cypr(2))];
            %       R3=[cosd(cypr(3)),-sind(cypr(3)),0;sind(cypr(3)),cosd(cypr(3)),0;0,0,1];

            % pad if necessary
            xyzRefinedPoints(end+1:size(allxy,1),:)=NaN;
            refinedErrors(end+1:size(allxy,1),:)=NaN;

            % set 0 values back to NaN
            xyzRefinedPoints(xyzRefinedPoints==0)=NaN;
            refinedErrors(refinedErrors==0)=NaN;

            % display results
            msg{numel(msg)+1}=['Primary camera bundle adjustment error: ',num2str(inanmean(refinedErrors))];
            app.msgTxtArea.Value=msg;


            %% END PRIMARY CAMERA CALIBRATION %%

            %% SECONDARY CAMERA CALIBRATION %%

            % estimate secondary camera extrinsics from 3D points determined in
            % the primary calibration

            % setup camera intrinsics
            scams=find(app.cameraTable.Data.primary==false);
            if numel(scams)>0

                columnID1=sort([scams'*2-1,scams'*2]); % column indices for the secondary cameras in the uv data arrays

                % build intrinsics array for the secondary cameras
                for i=1:numel(scams)
                    sintrinsics(i)=cameraIntrinsics(app.cameraTable.Data.f(scams(i)),[app.cameraTable.Data.Uo(scams(i)),app.cameraTable.Data.Vo(scams(i))],...
                        [app.cameraTable.Data.Vo(scams(i)),...
                        app.cameraTable.Data.Uo(scams(i))]*2,...
                        'RadialDistortion',table2array(app.cameraTable.Data(scams(i),6:8)));
                end

                for i=1:numel(scams)
                    columnID1a=columnID1(1,i*2-1:i*2);
                    % gather 2D and 3D points
                    % paired
                    try
                        % all wand points, regardless of whether or not they're non-NaN
                        wxy=[app.wandPts.data(:,[columnID1a,columnID1a+wandPtsOffset])];
                        wxyz=app.wandPts.xyz;
                    catch
                        wxy=[];
                        wxyz=[];
                    end
                    % unpaired
                    try
                        bxy=[app.bkgPts.data(:,columnID1a)];
                        bxyz=app.bkgPts.xyz;
                    catch
                        bxy=[];
                        bxyz=[];
                    end
                    % axis
                    try
                        axy=[app.axisPts.data(:,columnID1a)];
                        axyz=app.axisPts.xyz;
                    catch
                        axy=[];
                        axyz=[];
                    end

                    allxy=[wxy(:,1:size(wxy,2)/2);wxy(:,size(wxy,2)/2+1:end);bxy;axy];

                    % undistort the camera points if necessary
                    %allxy(isfinite(allxy(:,2)),1:2)=undistortPoints(allxy(isfinite(allxy(:,2)),1:2),sintrinsics(i));
                    allxy(isfinite(allxy(:,2)),1:2)=undistortFast(app,allxy(isfinite(allxy(:,2)),1:2),sintrinsics(i));

                    % xyz from the refined points created by bundle adjustment of
                    % primary cameras
                    allxyz=xyzRefinedPoints;


                    % estimate pose & orientation for secondary cameras using PnP
                    % algorithm. imagePoints = m x 2 array, worldPoints is m x 3 array,
                    % cameraParams is a cameraParameters or cameraIntrinsics object
                    try
                        goodData=find(allxy(:,1)~=0 & isnan(allxy(:,1))==false & allxyz(:,1)~=0 & isnan(allxyz(:,1))==false);
                        [sworldOrientation{i},sworldLocation{i}] = estimateWorldCameraPose(allxy(goodData,:),allxyz(goodData,:),...
                            sintrinsics(i),'MaxNumTrials',1000,'Confidence',1,'MaxReprojectionError',10);
                    catch ME
                        msg{numel(msg)+1}=ME.message;
                        msg{numel(msg)+1}='Error adding secondary camera, calibration abort.';
                        app.msgTxtArea.Value=msg;
                        return
                    end
                end

                % Now with the secondary cameras extrinsics estimated, re-optimize
                % the whole system

                % primary + secondary calibration
                allcams=[scams;pcams]; % secondary cameras are first
                columnID1=sort([scams'*2-1,scams'*2]);
                columnID2=sort([pcams'*2-1,pcams'*2]);
                columnID=[columnID1,columnID2];

                % gather points
                % paired
                try
                    % all wand points, regardless of whether or not they're non-NaN
                    wxy=[app.wandPts.data(:,[columnID,columnID+wandPtsOffset])];

                    % mask outliers
                    wxy(app.wandPts.outlier(:,1),1:numel(allcams)*2)=NaN;
                    wxy(app.wandPts.outlier(:,2),numel(allcams)*2+1:end)=NaN;
                catch
                    wxy=[];
                end
                % unpaired
                try
                    % all background points, regardless of whether or not they're non-NaN
                    bxy=app.bkgPts.data(:,columnID);

                    % mask outliers
                    bxy(app.bkgPts.outlier,:)=NaN;
                catch
                    bxy=[];
                end
                % axis
                try
                    axy=[app.axisPts.data(:,columnID)];
                catch
                    axy=[];
                end

                allxy=[wxy(:,1:size(wxy,2)/2);wxy(:,size(wxy,2)/2+1:end);bxy;axy];

                % setup intrinsics for all cameras
                for i=1:numel(allcams)
                    intrinsics(i)=cameraIntrinsics(app.cameraTable.Data.f(allcams(i)),...
                        [app.cameraTable.Data.Uo(allcams(i)),app.cameraTable.Data.Vo(allcams(i))],...
                        [app.cameraTable.Data.Vo(allcams(i)),app.cameraTable.Data.Uo(allcams(i))]*2,...
                        'RadialDistortion',table2array(app.cameraTable.Data(allcams(i),6:8)));
                end

                % undistort the camera points if necessary
                for i=1:numel(allcams)
                    % undistort the camera points if necessary
                    %allxy(isfinite(allxy(:,i*2)),i*2-1:i*2)=undistortPoints(allxy(isfinite(allxy(:,i*2)),i*2-1:i*2),intrinsics(i));
                    allxy(isfinite(allxy(:,i*2)),i*2-1:i*2)=undistortFast(app,allxy(isfinite(allxy(:,i*2)),i*2-1:i*2),intrinsics(i));
                end


                % setup poses for all cameras
                % make poses table
                %         R{end+1}=eye(3);
                %       tv{end+1}=[0,0,0];
                R={};
                tv={};
                cnt=1;
                for i=1:numel(sworldLocation)
                    R{cnt}=sworldOrientation{i};
                    tv{cnt}=sworldLocation{i};
                    cnt=cnt+1;
                end
                for i=1:size(refinedPoses,1)
                    R{cnt}=refinedPoses.Orientation{i};
                    tv{cnt}=refinedPoses.Location{i};
                    cnt=cnt+1;
                end
                ViewID=uint32(1:numel(allcams));
                cameraPosesAll=table(ViewID',R',tv','VariableNames',{'ViewId','Orientation','Location'});


                % setup tracks
                pointTracks=pointTrack();
                for i=1:size(allxy,1)
                    d=reshape(allxy(i,:),2,size(allxy,2)/2)';
                    vID=ViewID(sum(isfinite(d),2)==2);
                    if numel(vID)>1 %isempty(vID)==false
                        %pointTracks(i)=pointTrack(ViewID,reshape(allxy(i,:),2,size(allxy,2)/2)');
                        pointTracks(i)=pointTrack(vID,d(vID,:));
                    end
                end

                % get simple xyz result
                [xyzPoints,errors] = triangulateMultiview(pointTracks,cameraPosesAll,intrinsics);
                xyzPoints(isfinite(xyzPoints)~=true)=NaN; % remove inf values, replace with NaN
                xyzPointsB=xyzPoints;
                xyzPointsB(isfinite(xyzPointsB)~=true)=0; % enforce r2019b behavior in later versions for compatibility with bundleAdjustment()

                % bundle adjustment
                [xyzRefinedPoints,refinedPoses,refinedErrors] = bundleAdjustment(xyzPointsB,...
                    pointTracks,cameraPosesAll,intrinsics,'verbose',true,...
                    'pointsundistorted',true,'AbsoluteTolerance',.01);

                % pad if necessary
                xyzRefinedPoints(end+1:size(allxy,1),:)=NaN;
                refinedErrors(end+1:size(allxy,1),:)=NaN;
                
                % set zeros to NaN
                xyzRefinedPoints(xyzRefinedPoints==0)=NaN;
                refinedErrors(refinedErrors==0)=NaN;

                % set should-be zeros to NaN
                xyzRefinedPoints(sum(isfinite(allxy),2)<3,:)=NaN;
                refinedErrors(sum(isfinite(allxy),2)<3)=NaN;


                % display results
                msg{numel(msg)+1}=['Final camera bundle adjustment error: ',num2str(inanmean(refinedErrors))];
                app.msgTxtArea.Value=msg;


            else % no secondary cameras
                allcams=pcams;
            end % end of check for at least one secondary camera

            % setup pose & position for all cameras

            %             % enable the secondary calibration menu option
            %             app.computesecondarycalibrationMenu.Enable=true;

            %% SCALE SCENE %%
            if app.SetscenescalefrommeasuredcameracameradistancesCheckBox.Value && ...
                    sum(isfinite(table2array(app.CameraDistanceTable.Data(:,3))))>0
                % display info
                msg{numel(msg)+1}=['Using camera distances to set scene scale'];
                app.msgTxtArea.Value=msg;
                scaleD=[];
                % re-order the camera locations data to match the original camera
                % order (in case of re-arrangement of secondary cameras)
                orderedLocations(:,1)=allcams;
                orderedLocations(:,2:4)=cat(1,refinedPoses.Location{:});
                orderedLocations=sortrows(orderedLocations,1);
                orderedLocations(:,1)=[];
                for i=1:size(app.CameraDistanceTable.Data,1)
                    scaleD(i,1)=table2array(app.CameraDistanceTable.Data(i,3));
                    %FIX here - need to use other info
                    %scaleD(i,2)=rnorm(refinedPoses.Location{table2array(app.CameraDistanceTable.Data(i,1))}-...
                    %refinedPoses.Location{table2array(app.CameraDistanceTable.Data(i,2))});
                    scaleD(i,2)=rnorm(orderedLocations(table2array(app.CameraDistanceTable.Data(i,1)),:)-...
                        orderedLocations(table2array(app.CameraDistanceTable.Data(i,2)),:));
                end
                scaleCalc=scaleD(:,1)./scaleD(:,2);
                scaleFactor=inanmean(scaleCalc);
                scaleQuality=100*std(scaleCalc(isfinite(scaleCalc)))/inanmean(scaleCalc);
                if isnan(scaleFactor)
                    % display info
                    msg{numel(msg)+1}=['Scale information not present, setting scale to 1.0'];
                    msg{numel(msg)+1}=sprintf('Raw camera distances: %s ',sprintf('%.4f ',scaleD(:,2)));
                    app.msgTxtArea.Value=msg;
                    scaleFactor=1;
                else
                    msg{numel(msg)+1}=sprintf('Scaled camera distances: %s ',sprintf('%.4f ',scaleD(:,2)*scaleFactor));
                    msg{numel(msg)+1}=sprintf('Scale error: %.2f',scaleQuality);
                end
            elseif app.AlignmenttypeDropDown.Value==6 % known 3D point alignment
                % display info
                msg{numel(msg)+1}=['Setting scene scale from known inter-point distances'];
                app.msgTxtArea.Value=msg;

                % get ideal distance data
                iXYZ=app.axisPts.data(:,end-2:end);
                iDist=[];
                for i=1:size(iXYZ,1)
                    for j=i+1:size(iXYZ,1)
                        iDist=[iDist;rnorm(iXYZ(i,:)-iXYZ(j,:))];
                    end
                end

                % get current distances
                axyz=xyzRefinedPoints(end-size(axy,1)+1:end,:);
                aDist=[];
                for i=1:size(axyz,1)
                    for j=i+1:size(axyz,1)
                        aDist=[aDist;rnorm(axyz(i,:)-axyz(j,:))];
                    end
                end
                scaleCalc=iDist./aDist;
                scaleFactor=inanmean(scaleCalc);
                
                scaleScore=100*std(scaleCalc(isfinite(scaleCalc)))/inanmean(scaleCalc);

                msg{numel(msg)+1}=[sprintf('Scale error: %.2f%%',scaleScore)];
                app.msgTxtArea.Value=msg;

            else
                % display info
                msg{numel(msg)+1}=['Using wand length to set scene scale'];
                app.msgTxtArea.Value=msg;
                % wand scaling
                wxyz=nan(size(wxy,1),6);
                wxyz(:,1:3)=xyzRefinedPoints(1:size(wxy,1),1:3);
                wxyz(:,4:6)=xyzRefinedPoints(size(wxy,1)+1:size(wxy,1)*2,1:3);
                wxyz(wxyz==0)=NaN; % exact zeros should be NaNs
                wl=inanmean(rnorm(wxyz(:,1:3)-wxyz(:,4:6)));
                scaleFactor=app.InterpointdistanceEditField.Value/wl;
            end
            if isfinite(scaleFactor)==false
                scaleFactor=1;
            end

            % apply scale
            %             app.extrinsics.positions=cellfun(@(x) x*scaleFactor,app.extrinsics.positions,'UniformOutput',0);
            %             app.bkgPts.xyz=app.bkgPts.xyz*scaleFactor;
            refinedPoses.Location(:)=cellfun(@(x) x*scaleFactor,refinedPoses.Location(:),'UniformOutput',0);
            xyzRefinedPoints=xyzRefinedPoints*scaleFactor;


            %% ALIGN SCENE %%

            % get alignment type
            alType=app.AlignmenttypeDropDown.Value;
            lhFlag=false; % set to true if a left-handed coordinate system is detected in the xyzRefinedPoints
            if alType==5  % azimuth & elevation
                % display info
                msg{numel(msg)+1}=['Applying azimuth and elevation alignment'];
                app.msgTxtArea.Value=msg;
                try
                    % get the data
                    az=app.AzimuthangleofthelastcameradegreesEditField.Value;
                    el=app.ElevationangleofthelastcameradegreesEditField.Value;

                    % put the last camera in the standard position even if
                    % it was a secondary camera
                    idx=find(allcams==max(allcams));
                    if idx~=numel(allcams)
                        rotm=refinedPoses.Orientation{idx}';
                        o=refinedPoses.Location{idx};
                        refinedPoses.Location(:)=cellfun(@(x) x-o,refinedPoses.Location(:),'UniformOutput',0);
                        refinedPoses.Location(:)=cellfun(@(x) x*rotm,refinedPoses.Location(:),'UniformOutput',0);
                        refinedPoses.Orientation(:)=cellfun(@(x) x*rotm,refinedPoses.Orientation(:),'UniformOutput',0);
                    end

                    % perform rotation to "standard" pose with cameras looking along +X, +Z up and +Y left and re-triangulate
                    rotm=[0,-1,0;0,0,-1;1,0,0];
                    refinedPoses.Location(:)=cellfun(@(x) x*rotm,refinedPoses.Location(:),'UniformOutput',0);
                    refinedPoses.Orientation(:)=cellfun(@(x) x*rotm,refinedPoses.Orientation(:),'UniformOutput',0);

                    % now apply the measured azimuth and elevation (away from
                    % the standard pose as described above)

                    rotm=[cosd(az),-sind(az),0;sind(az),cosd(az),0;0,0,1];
                    rotm2=[cosd(el),0,sind(el);0,1,0;-sind(el),0,cosd(el)];
                    refinedPoses.Location(:)=cellfun(@(x) x*rotm2*rotm,refinedPoses.Location(:),'UniformOutput',0);
                    refinedPoses.Orientation(:)=cellfun(@(x) x*rotm2*rotm,refinedPoses.Orientation(:),'UniformOutput',0);

                    % re-triangulate with the new poses
                    [xyzRefinedPoints,refinedErrors] = triangulateMultiview(pointTracks,refinedPoses,intrinsics);
                    xyzRefinedPoints(isfinite(xyzRefinedPoints)~=true)=NaN;

                    % put the origin at the middle of the point cloud
                    meanPt=inanmean(xyzRefinedPoints);
                    refinedPoses.Location(:)=cellfun(@(x) x-meanPt,refinedPoses.Location(:),'UniformOutput',0);

                    % re-triangulate (again) with the new poses
                    [xyzRefinedPoints,refinedErrors] = triangulateMultiview(pointTracks,refinedPoses,intrinsics);
                    xyzRefinedPoints(isfinite(xyzRefinedPoints)~=true)=0; % restore r2019b output
                catch
                    % display info
                    msg{numel(msg)+1}=['Alignment operation failed!'];
                    app.msgTxtArea.Value=msg;

                end
            elseif alType==1 % 4 point axis

                if size(axy,1)==4
                    msg{numel(msg)+1}=['Applying 4-point axis alignment'];
                    axyz=xyzRefinedPoints(end-size(axy,1)+1:end,:);

                    o=axyz(1,:); % origin
                    axyz=axyz-repmat(o,4,1); % subtract origin
                    [th,ph] = cart2sph(axyz(2,1),axyz(2,2),axyz(2,3)); % +X axis position
                    rotm=[cos(th),-sin(th),0;sin(th),cos(th),0;0,0,1]; % un-yaw
                    rotm2=[cos(ph),0,sin(ph);0,1,0;-sin(ph),0,cos(ph)]'; % un-pitch
                    axyz=axyz*rotm*rotm2;
                    [th2]=cart2pol(axyz(3,2),axyz(3,3)); % un-roll
                    rotm3=[1,0,0;0,cos(th2),-sin(th2);0,sin(th2),cos(th2)];
                    axyz=axyz*rotm3;

                    % final fix on z
                    vZ=axyz(4,:)./norm(axyz(4,:));
                    ang=acos(dot(vZ,[0,0,sign(vZ(3))]));
                    ax=cross([0,0,sign(vZ(3))],vZ);
                    ax=ax./norm(ax);
                    c=cos(ang);
                    s=sin(ang);
                    t=(1-c);
                    rotm5=[t*ax(1)^2+c,t*ax(1)*ax(2)-ax(3)*s,t*ax(1)*ax(3)+ax(2)*s;...
                        t*ax(1)*ax(2)+ax(3)*s,t*ax(2)^2+c,t*ax(2)*ax(3)-ax(1)*s;...
                        t*ax(1)*ax(3)-ax(2)*s,t*ax(2)*ax(3)+ax(1)*s,t*ax(3)^2+c];
                    axyz=axyz*rotm5;

                    % check for a left-handed CS
                    if sum(sum(sign(axyz(2:4,:).*eye(3))==-1))>0
                        msg{numel(msg)+1}=['Detected a left-hand coordinate system - this will be corrected,'];
                        msg{numel(msg)+1}=['in the DLT coefficients but NOT in the camera extrinsics.'];
                        lhFlag=true;
                    end
                    lhCorrector=sum(sign(axyz(2:4,:)).*eye(3)); % multiplicative correction to rh coordinate system

                    % push to calibration
                    refinedPoses.Location(:)=cellfun(@(x) x-o,refinedPoses.Location(:),'UniformOutput',0);
                    refinedPoses.Location(:)=cellfun(@(x) x*rotm*rotm2*rotm3*rotm5,refinedPoses.Location(:),'UniformOutput',0);
                    refinedPoses.Orientation(:)=cellfun(@(x) x*rotm*rotm2*rotm3*rotm5,refinedPoses.Orientation(:),'UniformOutput',0);

                    % re-triangulate (again) with the new poses
                    [xyzRefinedPoints,refinedErrors] = triangulateMultiview(pointTracks,refinedPoses,intrinsics);
                    axyz2=xyzRefinedPoints(end-size(axy,1)+1:end,:);

                    % get non-orthogonality
                    axyz3=axyz2-repmat(axyz2(1,:),4,1);
                    axyz3(2,:)=axyz3(2,:)./norm(axyz3(2,:));
                    axyz3(3,:)=axyz3(3,:)./norm(axyz3(3,:));
                    axyz3(4,:)=axyz3(4,:)./norm(axyz3(4,:));
                    ia(1)=acosd(dot(axyz3(2,:),axyz3(3,:)));
                    ia(2)=acosd(dot(axyz3(3,:),axyz3(4,:)));
                    ia(3)=acosd(dot(axyz3(2,:),axyz3(4,:)));
                    ia=abs(ia-90);
                    msg{numel(msg)+1}=sprintf('Completed 4-point axis alignment, maximum non-orthogonality was %.2f degrees.', ...
                        max(ia));
                    app.msgTxtArea.Value=msg;
                else
                    msg{numel(msg)+1}=['4-point axis alignment selected, but',...
                        ' no appropriate axis data present; no alignment performed.'];
                    app.msgTxtArea.Value=msg;
                end
            elseif alType==2 % 3 point axis
                msg{numel(msg)+1}=['Applying 3-point axis alignment'];
                app.msgTxtArea.Value=msg;
                if size(axy,1)==3
                    axyz=xyzRefinedPoints(end-size(axy,1)+1:end,:);
                    o=axyz(1,:);
                    axyz=axyz-repmat(o,3,1); % subtract origin
                    [th,ph] = cart2sph(axyz(2,1),axyz(2,2),axyz(2,3));
                    rotm=[cos(th),-sin(th),0;sin(th),cos(th),0;0,0,1];
                    rotm2=[cos(ph),0,sin(ph);0,1,0;-sin(ph),0,cos(ph)]';
                    axyz(2,:)=axyz(2,:)*rotm*rotm2;
                    axyz(3,:)=axyz(3,:)*rotm*rotm2;
                    [th2]=cart2pol(axyz(3,2),axyz(3,3));
                    rotm3=[1,0,0;0,cos(th2),-sin(th2);0,sin(th2),cos(th2)];
                    axyz(3,:)*rotm3;

                    % push to calibration
                    refinedPoses.Location(:)=cellfun(@(x) x-o,refinedPoses.Location(:),'UniformOutput',0);
                    refinedPoses.Location(:)=cellfun(@(x) x*rotm*rotm2*rotm3,refinedPoses.Location(:),'UniformOutput',0);
                    refinedPoses.Orientation(:)=cellfun(@(x) x*rotm*rotm2*rotm3,refinedPoses.Orientation(:),'UniformOutput',0);

                    % re-triangulate (again) with the new poses
                    [xyzRefinedPoints,refinedErrors] = triangulateMultiview(pointTracks,refinedPoses,intrinsics);
                    axyz2=xyzRefinedPoints(end-size(axy,1)+1:end,:);

                    % get non-orthogonality
                    axyz3=axyz2-repmat(axyz2(1,:),3,1);
                    axyz3(2,:)=axyz3(2,:)./norm(axyz3(2,:));
                    axyz3(3,:)=axyz3(3,:)./norm(axyz3(3,:));
                    ia=acosd(dot(axyz3(2,:),axyz3(3,:)));
                    msg{numel(msg)+1}=sprintf('Completed 3-point axis alignment, maximum non-orthogonality was %.2f degrees.', ...
                        abs(90-ia));
                    app.msgTxtArea.Value=msg;
                else
                    msg{numel(msg)+1}=['3-point axis alignment selected, but',...
                        ' no appropriate axis data present; no alignment performed.'];
                    app.msgTxtArea.Value=msg;
                end
            elseif alType==3 % plane
                msg{numel(msg)+1}=['Aligning to a plane'];
                app.msgTxtArea.Value=msg;
                % get the xyz time-series
                axyz=xyzRefinedPoints(end-size(axy,1)+1:end,:);

                % put the origin at the mean of the axis
                o=inanmean(axyz);
                axyz=axyz-repmat(o,size(axyz,1),1); % subtract origin

                % do a principal components analysis to find the rotation that puts the least variation in Z,
                % i.e. exactly how a plane should look. Implement with SVD to avoid needing the stats toolbox
                [~,~,V]=svd(axyz(sum(isfinite(axyz),2)>0,:));

                % make sure V is a proper rotation matrix
                if det(V)<0
                    V=-V;
                end
                rotm5=V;

                axyz=axyz*rotm5;

                % push to calibration
                refinedPoses.Location(:)=cellfun(@(x) x-o,refinedPoses.Location(:),'UniformOutput',0);
                refinedPoses.Location(:)=cellfun(@(x) x*rotm5,refinedPoses.Location(:),'UniformOutput',0);
                refinedPoses.Orientation(:)=cellfun(@(x) x*rotm5,refinedPoses.Orientation(:),'UniformOutput',0);

                % re-triangulate (again) with the new poses
                [xyzRefinedPoints,refinedErrors] = triangulateMultiview(pointTracks,refinedPoses,intrinsics);
                xyzRefinedPoints(isfinite(xyzRefinedPoints)~=true)=NaN; % sensible output for unknown points

                % if the average Z point is negative, rotate by 180 about X
                if inanmean(xyzRefinedPoints(:,3))<0
                    rotm6=[1,0,0;0,cosd(180),-sind(180);0,sind(180),cosd(180)];
                    refinedPoses.Location(:)=cellfun(@(x) x*rotm6,refinedPoses.Location(:),'UniformOutput',0);
                    refinedPoses.Orientation(:)=cellfun(@(x) x*rotm6,refinedPoses.Orientation(:),'UniformOutput',0);
                    [xyzRefinedPoints,refinedErrors] = triangulateMultiview(pointTracks,refinedPoses,intrinsics);
                    axyz=axyz*rotm6;
                end

            elseif alType==4 % gravity
                if size(axy,1)>5
                    % get the xyz time-series
                    axyz=xyzRefinedPoints(end-size(axy,1)+1:end,:);
                    idx=find(isfinite(axyz(:,1)));
                    o=axyz(idx(1),:); % this will be the origin

                    % fit a 2nd order polynomial and get the derivative
                    g=app.polyFitGravity(axyz);
                    msg{numel(msg)+1}=[sprintf('gravity measured as %0.1f percent of actual',100*rnorm(g)/9.81)];

                    % rotate to point g along -Z
                    % turn the angle-axis rotation into a rotation matrix with the
                    % nifty exponential map method
                    vZ=g./norm(g);
                    ang=acos(dot(vZ,[0,0,-1]));
                    ax=cross([0,0,-1],vZ);
                    ax=ax./norm(ax);
                    axM=[0,-ax(3),ax(2);ax(3),0,-ax(1);-ax(2),ax(1),0];
                    rotm5=eye(3)+sin(ang)*axM+(1-cos(ang))*axM^2;
                    axyz=axyz*rotm5;

                    %push to calibration  [not working yet?]
                    refinedPoses.Location(:)=cellfun(@(x) x-o,refinedPoses.Location(:),'UniformOutput',0);
                    refinedPoses.Location(:)=cellfun(@(x) x*rotm5,refinedPoses.Location(:),'UniformOutput',0);
                    refinedPoses.Orientation(:)=cellfun(@(x) x*rotm5,refinedPoses.Orientation(:),'UniformOutput',0);

                    % re-triangulate (again) with the new poses
                    [xyzRefinedPoints,refinedErrors] = triangulateMultiview(pointTracks,refinedPoses,intrinsics);
                    %axyz2=xyzRefinedPoints(end-size(axy,1)+1:end,:);

                else
                    msg{numel(msg)+1}=['gravity alignment selected, but',...
                        ' no appropriate data present; no alignment performed.'];
                    app.msgTxtArea.Value=msg;
                end
            elseif alType==6 % 3+ known 3D points
                axyz=xyzRefinedPoints(end-size(axy,1)+1:end,:);

                % find points that are non-NaN in the axis points and known
                % locations arrays
                idx=find(sum(isfinite(axyz.*iXYZ),2)==3);
                axyzm=inanmean(axyz(idx,:));
                alp=axyz-axyzm;
                icm=inanmean(iXYZ(idx,:));
                ic=iXYZ-icm;

                % get the rotation using SVD
                % https://igl.ethz.ch/projects/ARAP/svd_rot.pdf
                H = (alp(idx,:))'*(ic(idx,:));
                [U,~,V]=svd(H);
                ar=eye(size(V,1)); % anti-reflection shim
                ar(end,end)=det(V*U');
                rotm=(V*ar*U')';

                % push to calibration
                refinedPoses.Location(:)=cellfun(@(x) x-axyzm,refinedPoses.Location(:),'UniformOutput',0);
                refinedPoses.Location(:)=cellfun(@(x) x*rotm,refinedPoses.Location(:),'UniformOutput',0);
                refinedPoses.Orientation(:)=cellfun(@(x) x*rotm,refinedPoses.Orientation(:),'UniformOutput',0);
                refinedPoses.Location(:)=cellfun(@(x) x+icm,refinedPoses.Location(:),'UniformOutput',0);

                % re-triangulate (again) with the new poses
                [xyzRefinedPoints,refinedErors] = triangulateMultiview(pointTracks,refinedPoses,intrinsics);
                axyz2=xyzRefinedPoints(end-size(axy,1)+1:end,:);
                foo=rnorm(axyz2(idx,:)-(ic(idx,:)+icm));
                msg{numel(msg)+1}=['Alignment to known 3D points complete.'];
                msg{numel(msg)+1}=sprintf('Median alignment point dimensional error: %.4f',median(foo(isfinite(foo))));
                msg{numel(msg)+1}=sprintf('Maximum alignment point dimensional error: %.4f',max(foo));

            else
                % display info
                msg{numel(msg)+1}=['You have selected an unimplemented alignment type'];
                msg{numel(msg)+1}=['No alignment operations performed'];
                app.msgTxtArea.Value=msg;
            end

            %% pass data back out %%
            % pad & fix any empties (created by outlier removal)
            xyzRefinedPoints(end+1:size(allxy,1),:)=NaN;
            xyzRefinedPoints(sum(xyzRefinedPoints==0,2)==3,:)=NaN;
            xyzRefinedPoints(isfinite(xyzRefinedPoints)~=true)=NaN;
            refinedErrors(end+1:size(allxy,1),:)=NaN;
            refinedErrors(refinedErrors==0)=NaN;
            refinedErorrs(isfinite(refinedErrors)~=true)=NaN;

            % fix left-handedness if necessary
            if lhFlag==true
                xyzRefinedPoints=xyzRefinedPoints.*lhCorrector;
            end

            % split xyzRefinedPoints back to wand, bkg, axis
            if size(axy,1)>0
                axyz=xyzRefinedPoints(end-size(axy,1)+1:end,:);
                xyzRefinedPoints(end-size(axy,1)+1:end,:)=[];

                aerr=refinedErrors(end-size(axy,1)+1:end,:);
                refinedErrors(end-size(axy,1)+1:end,:)=[];
            else
                axyz=zeros(0,3);
                aerr=zeros(0,1);
            end
            if size(bxy,1)>0
                bxyz=xyzRefinedPoints(end-size(bxy,1)+1:end,:);
                xyzRefinedPoints(end-size(bxy,1)+1:end,:)=[];

                berr=refinedErrors(end-size(bxy,1)+1:end,:);
                refinedErrors(end-size(bxy,1)+1:end,:)=[];
            else
                bxyz=zeros(0,3);
                berr=zeros(0,1);
            end
            if size(wxy,1)>0

                wxyz=nan(size(wxy,1),6);
                wxyz(:,1:3)=xyzRefinedPoints(1:size(xyzRefinedPoints,1)/2,1:3);
                wxyz(:,4:6)=xyzRefinedPoints(size(xyzRefinedPoints,1)/2+1:end,1:3);

                werr=nan(size(wxy,1),2);
                werr(:,1)=refinedErrors(1:size(xyzRefinedPoints,1)/2,1);
                werr(:,2)=refinedErrors(size(xyzRefinedPoints,1)/2+1:end,1);

                wandLen=rnorm(wxyz(:,1:3)-wxyz(:,4:6));

            else
                wxyz=zeros(0,6);
                werr=zeros(0,2);
                wandLen=NaN;
            end

            % push data back out
            if ~isempty(wxyz)
                app.wandPts.xyz=wxyz;
                app.wandPts.rmse=werr;
                app.wandPts.wandLength=rnorm(wxyz(:,1:3)-wxyz(:,4:6));
            end

            if ~isempty(bxyz)
                app.bkgPts.xyz=bxyz;
                app.bkgPts.rmse=berr;
            end

            if ~isempty(axyz)
                app.axisPts.xyz=axyz;
                app.axisPts.rmse=aerr;
            end

            % push the extrinsics back out
            for i=1:numel(allcams)
                app.extrinsics.poses{allcams(i)}=refinedPoses.Orientation{i};
                app.extrinsics.positions{allcams(i)}=refinedPoses.Location{i};
            end
            app.intrinsics=intrinsics(allcams);

            % compute DLT coefficients
            % numeric method
            dlt_computeCoefficients(app);

            %       % algebraic method
            %       c2=dlt_convertCoefficients(app); % doesn't work well with
            %       %non-SBA extrinsics?
            %       app.dltcoefs=c2;

            msg{numel(msg)+1}=['DLT conversion complete, camera residuals are:'];
            msg{numel(msg)+1}=[num2str(app.rmse)];
            app.msgTxtArea.Value=msg;

            % get wand score
            if size(wxy,1)>0
                wxy2=[allxy(1:size(wxy,1),:),allxy(size(wxy,1)+1:size(wxy,1)*2,:)]; % undistorted wand points
                [xyz1,rmse1]=dlt_reconstruct(app.dltcoefs(:,allcams),wxy2(:,1:app.numCams*2));
                [xyz2,rmse2]=dlt_reconstruct(app.dltcoefs(:,allcams),wxy2(:,app.numCams*2+1:end));
                wandLen=rnorm(xyz1-xyz2);
                msg{numel(msg)+1}=sprintf('Wand score: %.2f',std(wandLen(isfinite(wandLen)))/inanmean(wandLen)*100);
                app.msgTxtArea.Value=msg;

            end

            % enable the following after a successful calibration
            app.ExportDLTcoefficientsMenu.Enable=true;
            app.ExportcameraprofileMenu.Enable=true;

            % plot the scene
            plot3DReconstruct(app);

            % update the UI
            updateUnpairedPointsGrid(app);
            updatePairedPointsGrid(app);
            app.setworst5asoutliersMenu.Enable=true;

        end

        % Selection change function: TabGroup
        function TabGroupSelectionChanged(app, event)
            selectedTab = app.TabGroup.SelectedTab;

            %disp('tab group selection changed')
        end

        % Value changed function: ShowpointsfromDropDown
        function ShowpointsfromDropDownValueChanged(app, event)
            value = app.ShowpointsfromDropDown.Value;
            updateUnpairedPointsGrid(app)
        end

        % Cell edit callback: unpairedPointsTable
        function unpairedPointsTableCellEdit(app, event)
            indices = event.Indices;
            newData = event.NewData;
            app.bkgPts.outlier(indices(1))=~app.bkgPts.outlier(indices(1)); % flip the true-false value
        end

        % Menu selected function: SavethisprojectMenu
        function SavethisprojectMenuSelected(app, event)

            % get file save name and location
            [filename, pathname] = uiputfile('*.mat', 'Save simpleWand project as?');
            if filename==0
                app.msgTxtArea.Value={'Project save canceled'};
                return
            end

            % start creating the data save structure
            swSave=[];

            swSave.saveTime = datestr(now,'yyyy-mm-dd HH:MM:SS.FFF AM');
            swSave.simpleWand_version = app.version;

            % copy data in app properties
            swSave.data.axisPts = app.axisPts;
            swSave.data.bkgPts = app.bkgPts;
            swSave.data.wandPts = app.wandPts;
            swSave.data.extrinsics = app.extrinsics;
            swSave.data.intrinsics = app.intrinsics;
            swSave.data.numCams = app.numCams;
            swSave.data.wandScore = app.wandScore;
            swSave.data.distanceScore = app.distanceScore;
            swSave.data.rmse = app.rmse;
            swSave.data.dltcoefs = app.dltcoefs;
            swSave.data.imageOriginLowerLeft = app.imageOriginLowerLeft;

            % copy data and settings in UI elements
            swSave.data.cameraTable.Data = app.cameraTable.Data;
            swSave.data.CameraDistanceTable.Data = app.CameraDistanceTable.Data;
            swSave.data.ShowpointsfromDropDown.Items = app.ShowpointsfromDropDown.Items;
            swSave.data.ShowpointsfromDropDown.ItemsData = app.ShowpointsfromDropDown.ItemsData;
            swSave.data.ShowpointsfromDropDown_2.Items = app.ShowpointsfromDropDown_2.Items;
            swSave.data.ShowpointsfromDropDown_2.ItemsData = app.ShowpointsfromDropDown_2.ItemsData;
            swSave.data.ShowpointsfromDropDown.Value = app.ShowpointsfromDropDown.Value;
            swSave.data.AlignmentfileEditField.Value = app.AlignmentfileEditField.Value;
            swSave.data.AlignmenttypeDropDown.Value = app.AlignmenttypeDropDown.Value;
            swSave.data.SetscenescalefrommeasuredcameracameradistancesCheckBox.Value ...
                = app.SetscenescalefrommeasuredcameracameradistancesCheckBox.Value;
            swSave.data.PairedpointsfileEditField.Value = app.PairedpointsfileEditField.Value;
            swSave.data.InterpointdistanceEditField.Value = app.InterpointdistanceEditField.Value;
            swSave.data.UnpairedpointsfileEditField.Value = app.UnpairedpointsfileEditField.Value;
            swSave.data.CamerarecordingfrequencyHzEditField.Value = ...
                app.CamerarecordingfrequencyHzEditField.Value;
            swSave.data.AzimuthangleofthelastcameradegreesEditField.Value = ...
                app.AzimuthangleofthelastcameradegreesEditField.Value;
            swSave.data.ElevationangleofthelastcameradegreesEditField.Value = ...
                app.ElevationangleofthelastcameradegreesEditField.Value;

            % setup filename with appended _simpleWandData suffix, but don't double-up the
            % suffix
            filename2 = filename(1:end-4);
            s=strfind(filename2,'_simpleWandData');
            if isempty(s)==false
                filename2=filename2(1:s-1);
            end
            filename2 = [filename2,'_simpleWandData.mat'];
            swSave.savePath=pathname;
            swSave.saveName=filename(1:end-4);
            save([pathname,filename2],'swSave','-v7.3');
            app.msgTxtArea.Value={['simpleWand project saved as ',pathname,filename2]};

        end

        % Menu selected function: LoadasavedprojectMenu
        function LoadasavedprojectMenuSelected(app, event)
            msg={};

            % browse for the project file
            [filename,pathname] = uigetfile({'*_simpleWandData.mat','simpleWand project'},...
                'Pick a *_simpleWandData.mat file to load');
            if filename==0
                app.msgTxtArea.Value={'Load project canceled'};
                return
            end

            % load the file
            load([pathname,filename],'swSave');

            % check version
            if swSave.simpleWand_version(1)<0 & swSave.simpleWand_version(2)<5
                msg{numel(msg)+1}=('Sorry, this project is from an older version of simpleWand and may load incorrectly');
                app.msgTxtArea.Value=msg;
            end

            % restore data
            fn=fieldnames(swSave.data);
            for i=1:numel(fn)
                eval(['a=isstruct(swSave.data.',fn{i},');'])
                if a
                    eval(['fn2=fieldnames(swSave.data.',fn{i},');'])
                    for j=1:numel(fn2)
                        eval(['app.',fn{i},'.',fn2{j},'=swSave.data.',fn{i},'.',fn2{j},';'])
                    end
                else
                    eval(['app.',fn{i},'=swSave.data.',fn{i},';'])
                end
            end

            % update UI
            updateUnpairedPointsGrid(app);
            if numel(app.UnpairedpointsfileEditField.Value)>2
                app.OperationsMenu.Enable=true;
            end
            try
                plot3DReconstruct(app)
            catch ME
                % nothing
            end

            % turn on the UI
            app.NewprojectMenu.Enable=false;
            app.LoadasavedprojectMenu.Enable=false;
            app.SavethisprojectMenu.Enable=true;
            app.ExportcameraprofileMenu.Enable=true;
            app.DataMenu.Enable=true;
            app.updateAlignmentOptionVisibility();


            msg{numel(msg)+1}=(['Finished loading data from ',pathname,filename]);
            app.msgTxtArea.Value=msg;
        end

        % Menu selected function: ExportDLTcoefficientsMenu
        function ExportDLTcoefficientsMenuSelected(app, event)
            msg={};
            % get save name and path
            [fname,pname]=uiputfile({'*.csv','comma-separated value files (*.csv)'});
            if fname~=0
                if strcmpi(fname(end-3:end),'.csv')
                    fname=fname(1:end-4);
                end
                dlmwrite([pname,fname,'_dltCoefs.csv'],app.dltcoefs,',');
                msg{numel(msg)+1}=(['DLT coefficients exported as ',pname,fname,'_dltCoefs.csv']);
                for i=1:app.numCams
                    name=['_cam',num2str(i),'Tforms'];
                    if sum(abs(app.intrinsics(i).RadialDistortion(:)))>0
                        create_Tforms(app.intrinsics(i).FocalLength,app.intrinsics(i).PrincipalPoint,...
                            [app.intrinsics(i).RadialDistortion(1:2),...
                            app.intrinsics(i).TangentialDistortion,app.intrinsics(i).RadialDistortion(3)],...
                            fliplr(app.intrinsics(i).ImageSize),[pname,fname,name]);
                        msg{numel(msg)+1}=sprintf('Exported undistortion tforms for camera %.0f as %s',i,name);
                    end
                    %create_Tforms(fl,pp,kc,res,[pname,fname,name]);
                end
            else
                msg{numel(msg)+1}=(['Export DLT coefficients canceled.']);
            end
            app.msgTxtArea.Value=msg;
        end

        % Menu selected function: LicenseMenu
        function LicenseMenuSelected(app, event)
            msgbox(app.licenseText);
        end

        % Menu selected function: AboutMenu
        function AboutMenuSelected(app, event)
            txt=sprintf('This is simpleWand version %d.%d.%d',app.version(1),app.version(2),app.version(3));
            msgbox(txt)
        end

        % Menu selected function: QuitMenu
        function QuitMenuSelected(app, event)
            delete(app);
        end

        % Menu selected function: clearoutlierdesignationsMenu
        function clearoutlierdesignationsMenuSelected(app, event)
            app.msgTxtArea.Value={'Clear outlier designations selected'};
            app.bkgPts.outlier=false(size(app.bkgPts.data,1),1);
            updateUnpairedPointsGrid(app)

            app.wandPts.outlier=false(size(app.wandPts.data,1),2);
            updatePairedPointsGrid(app)
        end

        % Value changed function: ShowpointsfromDropDown_2
        function ShowpointsfromDropDown_2ValueChanged(app, event)
            updatePairedPointsGrid(app)
        end

        % Cell edit callback: pairedPointsTable
        function pairedPointsTableCellEdit(app, event)
            indices = event.Indices;
            newData = event.NewData;
            pt=-mod(app.ShowpointsfromDropDown_2.Value,2)+2;
            app.wandPts.outlier(indices(1),pt)=~app.wandPts.outlier(indices(1),pt); % flip the true-false value
        end

        % Value changed function: AlignmenttypeDropDown
        function AlignmenttypeDropDownValueChanged(app, event)
            value = app.AlignmenttypeDropDown.Value;
            app.updateAlignmentOptionVisibility();
        end

        % Menu selected function: ExportcameraprofileMenu
        function ExportcameraprofileMenuSelected(app, event)
            % setup camera profile array
            camProf=[app.cameraTable.Data.num, app.cameraTable.Data.f,app.cameraTable.Data.Uo*2, ...
                app.cameraTable.Data.Vo*2,app.cameraTable.Data.Uo,app.cameraTable.Data.Vo, ...
                app.cameraTable.Data.num*0+1,app.cameraTable.Data.d2,app.cameraTable.Data.d4,...
                app.cameraTable.Data.num*0,app.cameraTable.Data.num*0,app.cameraTable.Data.d6];

            % get save name and path
            [f,p]=uiputfile({'*.txt','Text files (*.txt)'},'Save profile as');

            if f~=0
                if strcmpi(f(end-3:end),'.txt')
                    f=f(1:end-4);
                end
                dlmwrite([p,f,'_profile.txt'],camProf,' ');
                mess{1}=sprintf('Profile saved to %s%s_profile.txt',p,f);
            else
                mess{1}='Profile not saved';
            end

            app.msgTxtArea.Value=mess;


        end

        % Menu selected function: SetimageoriginMenu
        function SetimageoriginMenuSelected(app, event)
            % lower left origin isn't ready yet
            bn = questdlg('Where is the image coordinate origin?','Image origin?','Upper left','Lower left','Upper left');
            if strcmpi('Lower left',bn)
                app.imageOriginLowerLeft=1;
            else
                app.imageOriginLowerLeft=0;
            end
        end

        % Menu selected function: setworst5asoutliersMenu
        function setworst5asoutliersMenuSelected(app, event)
            % accumulate all errors
            allrmse=reshape(app.wandPts.rmse,numel(app.wandPts.rmse),1);
            allrmse=[allrmse;app.bkgPts.rmse];
            allrmse=sort(allrmse);
            allrmse(isnan(allrmse))=[];

            % find the cutoff
            cutoff=allrmse(round(numel(allrmse)*0.95));

            % set new outliers
            idx=find(app.wandPts.rmse(:,1)>cutoff);
            app.wandPts.outlier(idx,1)=true;
            idx=find(app.wandPts.rmse(:,2)>cutoff);
            app.wandPts.outlier(idx,2)=true;
            idx=find(app.bkgPts.rmse(:,1)>cutoff);
            app.bkgPts.outlier(idx,1)=true;

            % re-run the calibration
            computecalibrationMenuSelected(app, event)

        end

        % Menu selected function: CheckforupdateMenu
        function CheckforupdateMenuSelected(app, event)
            v=webread('https://raw.githubusercontent.com/tlhedrick/simpleWand/main/currentVersion.txt');
            idx=strfind(v,'.');
            vMajor=str2num(v(1:idx(1)-1));
            vMinor=str2num(v(idx(1)+1:idx(2)-1));
            vPoint=str2num(v(idx(2)+1:end));

            % from the app
            versionMajor=app.version(1);
            versionMinor=app.version(2);
            versionPoint=app.version(3);
            
            if vMajor>versionMajor || (vMinor > versionMinor & vMajor==versionMajor) || (vPoint > versionPoint & vMajor==versionMajor & vMinor==versionMinor)
                ButtonName = questdlg('There is a newer version at https://github.com/tlhedrick/simpleWand', 'Version update?', 'OK','Go get it','OK');
                if strcmpi(ButtonName,'Go get it')
                    web('https://github.com/tlhedrick/simpleWand')
                end
            else
                msgbox('You are using the latest version')
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.simpleWandUIFigure = uifigure('Visible', 'off');
            app.simpleWandUIFigure.Name = 'simpleWand';
            app.ProjectMenu = uimenu(app.simpleWandUIFigure);
            app.ProjectMenu.Text = 'Project';
            app.NewprojectMenu = uimenu(app.ProjectMenu);
            app.NewprojectMenu.MenuSelectedFcn = createCallbackFcn(app, @NewprojectMenuSelected, true);
            app.NewprojectMenu.Tooltip = {'Start a new project'};
            app.NewprojectMenu.Text = 'New project';
            app.LoadasavedprojectMenu = uimenu(app.ProjectMenu);
            app.LoadasavedprojectMenu.MenuSelectedFcn = createCallbackFcn(app, @LoadasavedprojectMenuSelected, true);
            app.LoadasavedprojectMenu.Tooltip = {'Load a saved project'};
            app.LoadasavedprojectMenu.Text = 'Load a saved project';
            app.SavethisprojectMenu = uimenu(app.ProjectMenu);
            app.SavethisprojectMenu.MenuSelectedFcn = createCallbackFcn(app, @SavethisprojectMenuSelected, true);
            app.SavethisprojectMenu.Tooltip = {'Save the current project as a MATLAB data file'};
            app.SavethisprojectMenu.Enable = 'off';
            app.SavethisprojectMenu.Text = 'Save this project';
            app.ExportcameraprofileMenu = uimenu(app.ProjectMenu);
            app.ExportcameraprofileMenu.MenuSelectedFcn = createCallbackFcn(app, @ExportcameraprofileMenuSelected, true);
            app.ExportcameraprofileMenu.Tooltip = {'Export the camera profile in text format'};
            app.ExportcameraprofileMenu.Enable = 'off';
            app.ExportcameraprofileMenu.Text = 'Export camera profile';
            app.ExportDLTcoefficientsMenu = uimenu(app.ProjectMenu);
            app.ExportDLTcoefficientsMenu.MenuSelectedFcn = createCallbackFcn(app, @ExportDLTcoefficientsMenuSelected, true);
            app.ExportDLTcoefficientsMenu.Tooltip = {'Export the calibration as DLT coefficients for use with DLTdv and elsewhere'};
            app.ExportDLTcoefficientsMenu.Enable = 'off';
            app.ExportDLTcoefficientsMenu.Text = 'Export DLT coefficients';
            app.QuitMenu = uimenu(app.ProjectMenu);
            app.QuitMenu.MenuSelectedFcn = createCallbackFcn(app, @QuitMenuSelected, true);
            app.QuitMenu.Tooltip = {'Quit the simpleWand app'};
            app.QuitMenu.Text = 'Quit';
            app.DataMenu = uimenu(app.simpleWandUIFigure);
            app.DataMenu.Enable = 'off';
            app.DataMenu.Text = 'Data';
            app.LoadpairedpointsMenu = uimenu(app.DataMenu);
            app.LoadpairedpointsMenu.MenuSelectedFcn = createCallbackFcn(app, @LoadpairedpointsMenuSelected, true);
            app.LoadpairedpointsMenu.Tooltip = {'Load wand or paired points; the physical distance between the paired points should be constant'};
            app.LoadpairedpointsMenu.Text = 'Load paired points';
            app.LoadunpairedpointsMenu = uimenu(app.DataMenu);
            app.LoadunpairedpointsMenu.MenuSelectedFcn = createCallbackFcn(app, @LoadunpairedpointsMenuSelected, true);
            app.LoadunpairedpointsMenu.Tooltip = {'Load a set of corresponding points; csv format with [u,v] columns for each camera.'};
            app.LoadunpairedpointsMenu.Text = 'Load unpaired points';
            app.LoadalignmentfileMenu = uimenu(app.DataMenu);
            app.LoadalignmentfileMenu.MenuSelectedFcn = createCallbackFcn(app, @LoadalignmentfileMenuSelected, true);
            app.LoadalignmentfileMenu.Tooltip = {'Load an alignment file, see the Alignment tab for details'};
            app.LoadalignmentfileMenu.Text = 'Load alignment file';
            app.LoadacameraprofileMenu = uimenu(app.DataMenu);
            app.LoadacameraprofileMenu.MenuSelectedFcn = createCallbackFcn(app, @LoadacameraprofileMenuSelected, true);
            app.LoadacameraprofileMenu.Tooltip = {'Load a camera profile'};
            app.LoadacameraprofileMenu.Text = 'Load a camera profile';
            app.SetimageoriginMenu = uimenu(app.DataMenu);
            app.SetimageoriginMenu.MenuSelectedFcn = createCallbackFcn(app, @SetimageoriginMenuSelected, true);
            app.SetimageoriginMenu.Tooltip = {'Set the image coordinate origin, either in the upper left corner (typical) or lower left corner (DLTdv7 & easyWand) [not ready yet]'};
            app.SetimageoriginMenu.Enable = 'off';
            app.SetimageoriginMenu.Text = 'Set image origin';
            app.OperationsMenu = uimenu(app.simpleWandUIFigure);
            app.OperationsMenu.Enable = 'off';
            app.OperationsMenu.Text = 'Operations';
            app.computecalibrationMenu = uimenu(app.OperationsMenu);
            app.computecalibrationMenu.MenuSelectedFcn = createCallbackFcn(app, @computecalibrationMenuSelected, true);
            app.computecalibrationMenu.Tooltip = {'Run the wand calibration operation for the primary cameras'};
            app.computecalibrationMenu.Text = 'compute calibration';
            app.clearoutlierdesignationsMenu = uimenu(app.OperationsMenu);
            app.clearoutlierdesignationsMenu.MenuSelectedFcn = createCallbackFcn(app, @clearoutlierdesignationsMenuSelected, true);
            app.clearoutlierdesignationsMenu.Tooltip = {'Make all points inliers'};
            app.clearoutlierdesignationsMenu.Text = 'clear outlier designations';
            app.setworst5asoutliersMenu = uimenu(app.OperationsMenu);
            app.setworst5asoutliersMenu.MenuSelectedFcn = createCallbackFcn(app, @setworst5asoutliersMenuSelected, true);
            app.setworst5asoutliersMenu.Tooltip = {'Makes the worst 5% of the paired & unpaired points "outliers" and re-runs the calibration.'};
            app.setworst5asoutliersMenu.Enable = 'off';
            app.setworst5asoutliersMenu.Text = 'set worst 5% as outliers';
            app.HelpMenu = uimenu(app.simpleWandUIFigure);
            app.HelpMenu.Text = 'Help';
            app.AboutMenu = uimenu(app.HelpMenu);
            app.AboutMenu.MenuSelectedFcn = createCallbackFcn(app, @AboutMenuSelected, true);
            app.AboutMenu.Tooltip = {'Information on simpleWand'};
            app.AboutMenu.Text = 'About';
            app.LicenseMenu = uimenu(app.HelpMenu);
            app.LicenseMenu.MenuSelectedFcn = createCallbackFcn(app, @LicenseMenuSelected, true);
            app.LicenseMenu.Tooltip = {'Source code license'};
            app.LicenseMenu.Text = 'License';
            app.ManualMenu = uimenu(app.HelpMenu);
            app.ManualMenu.Enable = 'off';
            app.ManualMenu.Text = 'Manual';
            app.CheckforupdateMenu = uimenu(app.HelpMenu);
            app.CheckforupdateMenu.MenuSelectedFcn = createCallbackFcn(app, @CheckforupdateMenuSelected, true);
            app.CheckforupdateMenu.Tooltip = {'Check the github repository to see if a new version is available.'};
            app.CheckforupdateMenu.Text = 'Check for update';
            app.plotAxes = uiaxes(app.simpleWandUIFigure);
            title(app.plotAxes, 'Calibration scene')
            xlabel(app.plotAxes, 'X')
            ylabel(app.plotAxes, 'Y')
            app.cameraTable = uitable(app.simpleWandUIFigure);
            app.cameraTable.ColumnName = {'Cam'; 'Primary'; 'Horiz. Principal point'; 'Vertical Principal point'; 'Focal length'; 'Distortion r^2'; 'Distortion r^4'; 'Distortion r^6'};
            app.cameraTable.RowName = {};
            app.cameraTable.ColumnEditable = [false true true true true true true true];
            app.TabGroup = uitabgroup(app.simpleWandUIFigure);
            app.TabGroup.SelectionChangedFcn = createCallbackFcn(app, @TabGroupSelectionChanged, true);
            app.PairedpointsTab = uitab(app.TabGroup);
            app.PairedpointsTab.Tooltip = {'Tab showing paired point information'};
            app.PairedpointsTab.Title = 'Paired points';
            app.InterpointdistanceEditFieldLabel = uilabel(app.PairedpointsTab);
            app.InterpointdistanceEditFieldLabel.Text = 'Inter-point distance';
            app.InterpointdistanceEditField = uieditfield(app.PairedpointsTab, 'numeric');
            app.InterpointdistanceEditField.Limits = [0 Inf];
            app.InterpointdistanceEditField.Value = 1;
            app.pairedPointsTable = uitable(app.PairedpointsTab);
            app.pairedPointsTable.ColumnName = {'ID'; 'u'; 'v'; 'x'; 'y'; 'z'; 'rmse'; 'wand length'; 'outlier'};
            app.pairedPointsTable.RowName = {};
            app.pairedPointsTable.ColumnSortable = true;
            app.pairedPointsTable.ColumnEditable = [false false false false false false false false true];
            app.pairedPointsTable.CellEditCallback = createCallbackFcn(app, @pairedPointsTableCellEdit, true);
            app.PairedpointsfileEditFieldLabel = uilabel(app.PairedpointsTab);
            app.PairedpointsfileEditFieldLabel.Text = 'Paired points file:';
            app.PairedpointsfileEditField = uieditfield(app.PairedpointsTab, 'text');
            app.PairedpointsfileEditField.Editable = 'off';
            app.PairedpointsfileEditField.Tooltip = {'Path and filename of the paired points file'};
            app.PairedpointsfileEditField.Value = 'none';
            app.ShowpointsfromDropDown_2Label = uilabel(app.PairedpointsTab);
            app.ShowpointsfromDropDown_2Label.Text = 'Show points from:';
            app.ShowpointsfromDropDown_2 = uidropdown(app.PairedpointsTab);
            app.ShowpointsfromDropDown_2.ValueChangedFcn = createCallbackFcn(app, @ShowpointsfromDropDown_2ValueChanged, true);
            app.UnpairedpointsTab = uitab(app.TabGroup);
            app.UnpairedpointsTab.Tooltip = {'Tab showing unpaired point information'};
            app.UnpairedpointsTab.Title = 'Unpaired points';
            app.unpairedPointsTable = uitable(app.UnpairedpointsTab);
            app.unpairedPointsTable.ColumnName = {'ID'; 'u'; 'v'; 'x'; 'y'; 'z'; 'error'; 'outlier'};
            app.unpairedPointsTable.RowName = {};
            app.unpairedPointsTable.ColumnSortable = true;
            app.unpairedPointsTable.ColumnEditable = [false false false false false false false true];
            app.unpairedPointsTable.CellEditCallback = createCallbackFcn(app, @unpairedPointsTableCellEdit, true);
            app.unpairedPointsTable.Tooltip = {'Mark points as outliers if desired.'};
            app.UnpairedpointsfileEditFieldLabel = uilabel(app.UnpairedpointsTab);
            app.UnpairedpointsfileEditFieldLabel.Text = 'Unpaired points file:';
            app.UnpairedpointsfileEditField = uieditfield(app.UnpairedpointsTab, 'text');
            app.UnpairedpointsfileEditField.Editable = 'off';
            app.UnpairedpointsfileEditField.Tooltip = {'Path and filename of the unpaired points file'};
            app.UnpairedpointsfileEditField.Value = 'none';
            app.ShowpointsfromDropDownLabel = uilabel(app.UnpairedpointsTab);
            app.ShowpointsfromDropDownLabel.Text = 'Show points from:';
            app.ShowpointsfromDropDown = uidropdown(app.UnpairedpointsTab);
            app.ShowpointsfromDropDown.ValueChangedFcn = createCallbackFcn(app, @ShowpointsfromDropDownValueChanged, true);
            app.AlignmentPointsTab = uitab(app.TabGroup);
            app.AlignmentPointsTab.Tooltip = {'Tab with the different alignment options for the 3D scene'};
            app.AlignmentPointsTab.Title = 'Alignment';
            app.AlignmenttypeDropDownLabel = uilabel(app.AlignmentPointsTab);
            app.AlignmenttypeDropDownLabel.Text = 'Alignment type';
            app.AlignmenttypeDropDown = uidropdown(app.AlignmentPointsTab);
            app.AlignmenttypeDropDown.Items = {'4 point axis (origin, +x, +y, +z)', '3 point axis (origin, +x, +y)', 'plane', 'gravity', 'camera azimuth & elevation', '3+ known 3D points'};
            app.AlignmenttypeDropDown.ItemsData = {'1', '2', '3', '4', '5', '6'};
            app.AlignmenttypeDropDown.ValueChangedFcn = createCallbackFcn(app, @AlignmenttypeDropDownValueChanged, true);
            app.AlignmenttypeDropDown.Value = '5';
            app.AlignmentfileEditFieldLabel = uilabel(app.AlignmentPointsTab);
            app.AlignmentfileEditFieldLabel.Text = 'Alignment file:';
            app.AlignmentfileEditField = uieditfield(app.AlignmentPointsTab, 'text');
            app.AlignmentfileEditField.Editable = 'off';
            app.AlignmentfileEditField.Tooltip = {'Path and filename of the unpaired points file'};
            app.AlignmentfileEditField.Value = 'none';
            app.AlignmentsettingsPanel = uipanel(app.AlignmentPointsTab);
            app.AlignmentsettingsPanel.Title = 'Alignment settings';
            app.CamerarecordingfrequencyHzEditFieldLabel = uilabel(app.AlignmentsettingsPanel);
            app.CamerarecordingfrequencyHzEditFieldLabel.Visible = 'off';
            app.CamerarecordingfrequencyHzEditFieldLabel.Text = 'Camera recording frequency (Hz)';
            app.CamerarecordingfrequencyHzEditField = uieditfield(app.AlignmentsettingsPanel, 'numeric');
            app.CamerarecordingfrequencyHzEditField.Limits = [0.0001 Inf];
            app.CamerarecordingfrequencyHzEditField.Visible = 'off';
            app.CamerarecordingfrequencyHzEditField.Tooltip = {'Specify the recording frequency for aligning to gravity from a trajectory'};
            app.CamerarecordingfrequencyHzEditField.Value = 100;
            app.AzimuthangleofthelastcameradegreesEditFieldLabel = uilabel(app.AlignmentsettingsPanel);
            app.AzimuthangleofthelastcameradegreesEditFieldLabel.Visible = 'off';
            app.AzimuthangleofthelastcameradegreesEditFieldLabel.Text = 'Azimuth angle of the last camera (degrees)';
            app.AzimuthangleofthelastcameradegreesEditField = uieditfield(app.AlignmentsettingsPanel, 'numeric');
            app.AzimuthangleofthelastcameradegreesEditField.Limits = [-360 360];
            app.AzimuthangleofthelastcameradegreesEditField.Visible = 'off';
            app.AzimuthangleofthelastcameradegreesEditField.Tooltip = {'Specify the azimuth of the last camera in degrees.'};
            app.ElevationangleofthelastcameradegreesEditFieldLabel = uilabel(app.AlignmentsettingsPanel);
            app.ElevationangleofthelastcameradegreesEditFieldLabel.Visible = 'off';
            app.ElevationangleofthelastcameradegreesEditFieldLabel.Text = 'Elevation angle of the last camera (degrees)';
            app.ElevationangleofthelastcameradegreesEditField = uieditfield(app.AlignmentsettingsPanel, 'numeric');
            app.ElevationangleofthelastcameradegreesEditField.Limits = [-360 360];
            app.ElevationangleofthelastcameradegreesEditField.Visible = 'off';
            app.ElevationangleofthelastcameradegreesEditField.Tooltip = {'Enter the elevation angle of the last camera in degrees'};
            app.NosettingsforthisalignmenttypeLabel = uitextarea(app.AlignmentsettingsPanel);
            app.NosettingsforthisalignmenttypeLabel.Editable = 'off';
            app.NosettingsforthisalignmenttypeLabel.Value = {'No settings for this alignment type.'};
            app.alignmentPointsTable = uitable(app.AlignmentPointsTab);
            app.alignmentPointsTable.ColumnName = {'Column 1'; 'Column 2'; 'Column 3'; 'Column 4'};
            app.alignmentPointsTable.RowName = {};
            app.ScaleTab = uitab(app.TabGroup);
            app.ScaleTab.Title = 'Scale';
            app.SetscenescalefrommeasuredcameracameradistancesCheckBox = uicheckbox(app.ScaleTab);
            app.SetscenescalefrommeasuredcameracameradistancesCheckBox.Tooltip = {'Option to set the scene scale from camera distances instead of inter-paired-point distance'};
            app.SetscenescalefrommeasuredcameracameradistancesCheckBox.Text = 'Set scene scale from measured camera-camera distances';
            app.CameraDistanceTable = uitable(app.ScaleTab);
            app.CameraDistanceTable.ColumnName = {'Camera 1 ID'; 'Camera 2 ID'; 'camera-camera distance'};
            app.CameraDistanceTable.RowName = {};
            app.CameraDistanceTable.ColumnSortable = true;
            app.CameraDistanceTable.ColumnEditable = [false false true];
            app.CameraDistanceTable.Tooltip = {'Fill in the distances between at least one pair of cameras to enable setting the scale from this information'};
            app.msgTxtArea = uitextarea(app.simpleWandUIFigure);
            % Show the figure after all components are created
            app.simpleWandUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = simpleWand

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.simpleWandUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.simpleWandUIFigure)
        end
    end
end